classdef calc6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        BoxUVskinAndEye             matlab.ui.control.CheckBox
        BoxUVA                      matlab.ui.control.CheckBox
        BoxBlueLight                matlab.ui.control.CheckBox
        BoxRetinaLr                 matlab.ui.control.CheckBox
        BoxInfraRed                 matlab.ui.control.CheckBox
        LabelBoxSelection           matlab.ui.control.Label
        VbrtypuzdrojeDropDownLabel  matlab.ui.control.Label
        SelectSpectrum              matlab.ui.control.DropDown
        ResultTable                 matlab.ui.control.Table
        VzdlenostEditFieldLabel     matlab.ui.control.Label
        FieldDistance               matlab.ui.control.NumericEditField
        LabelDistance               matlab.ui.control.Label
        StednrozmrEditFieldLabel    matlab.ui.control.Label
        FieldMedianDimension        matlab.ui.control.NumericEditField
        LabelMedianDimension        matlab.ui.control.Label
        OsvtlenostEditFieldLabel    matlab.ui.control.Label
        FieldIlluminance            matlab.ui.control.NumericEditField
        lxLabel                     matlab.ui.control.Label
        LabelSelectSource           matlab.ui.control.Label
        SelectSourceType            matlab.ui.control.DropDown
        SelectSpecificSource        matlab.ui.control.DropDown
        SpinnerSource               matlab.ui.control.Spinner
        LabelTemperature            matlab.ui.control.Label
        Temperature                 matlab.ui.control.NumericEditField
        BoxAge                      matlab.ui.control.CheckBox
        LabelAgeBox                 matlab.ui.control.Label
        FieldAgeNumber              matlab.ui.control.NumericEditField
        LabelAge                    matlab.ui.control.Label
        ButtonImport                matlab.ui.control.Button
        BoxEyeFakic                 matlab.ui.control.CheckBox
        BoxEyeAfakic                matlab.ui.control.CheckBox
        BoxEyePseudofakic           matlab.ui.control.CheckBox
        SelectPseudofakic           matlab.ui.control.DropDown
        LabelRG0                    matlab.ui.control.Label
        LabelRG0Es                  matlab.ui.control.Label
        FieldRG0Es                  matlab.ui.control.NumericEditField
        LabelRG0EUVA                matlab.ui.control.Label
        FieldRG0EUVA                matlab.ui.control.NumericEditField
        LabelRG0Eb                  matlab.ui.control.Label
        FieldRG0Eb                  matlab.ui.control.NumericEditField
        LabelRG0Lr                  matlab.ui.control.Label
        FieldRG0Lr                  matlab.ui.control.NumericEditField
        CopytoclipboardButton       matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        SaveSpectra                 matlab.ui.control.Button
        DeleteSpectra               matlab.ui.control.Button
        LabelSafeDistance_1         matlab.ui.control.Label
        LabelSafeDistance_2         matlab.ui.control.Label
        LabelSafeDistance_3         matlab.ui.control.Label
        LabelSafeDistance_4         matlab.ui.control.Label
    end

    
    properties (Access = private)
        slash % Description
        spectraRow % Description
        sourceRow % Description
        importedWavelength % Description
        importedValues % Description
        LD % Here are stored all the variables
    end
    
    methods (Access = private)
        
        function c=brdrIntegral(app, a, b, data, weights, step, t)
            if app.BoxAge.Value == true && t == 1
                tauNormalized(:,1)=app.LD.tau32(:,1);
                D=0.06+(0.15+app.FieldAgeNumber.Value^2*3.1*10^-5).*(400./tauNormalized(:,1)).^4 ...
                    +151.5492.*exp(-(0.057.*(tauNormalized(:,1)-273)).^2)...
                    +2.13.*(1.05-app.FieldAgeNumber.Value^2*6.3*10^-5).*exp(-(0.029.*(tauNormalized(:,1)-370)).^2)...
                    +11.95.*(0.059+app.FieldAgeNumber.Value^2*1.86*10^-4).*exp(-(0.021.*(tauNormalized(:,1)-325)).^2)...
                    +1.43.*(0.016+app.FieldAgeNumber.Value^2*1.32*10^-4).*exp(-(0.008.*(tauNormalized(:,1)-325)).^2);
                tau=10.^-D;
                tauNormalized(:,2)=tau(:)./app.LD.tau32(:,2);
                tauNormalized=[tauNormalized;[(801:3000)',ones(2200,1)]];
            else
                tauNormalized=[(200:3000)', ones([2801 1])];
            end
            
            botTable=(length(data)>=(a-data(1,1))/step+1)*(1<(a-data(1,1))/step+1)*((a-data(1,1))/step+1)+...
                (1>=(a-data(1,1))/step+1)*1;
            topTable=(length(data)>=(b-data(1,1))/step+1)*(length(data)>(b-data(1,1))/step+1)*((b-data(1,1))/step+1)+...
                (length(data)<=(b-data(1,1))/step+1)*length(data);
            
            weights=weights(uint16((0:(length(weights)-1)/step)*step+1),:);
            tauNormalized=tauNormalized(uint16((0:(length(tauNormalized)-1)/step)*step+1),:);
            
            if botTable<=0 || topTable<=0
                c=0;
            else
                botWeights=(data(botTable,1)-weights(1,1))/step+1;
                topWeights=(data(topTable,1)-weights(1,1))/step+1;
                
                botTauNormalized=(data(botTable,1)-tauNormalized(1,1))/step+1;
                topTauNormalized=(data(topTable,1)-tauNormalized(1,1))/step+1;
                
                if weights(1,1)==0
                    weights=ones([(topTable-botTable+1) 2]);
                    c=sum(data(botTable:topTable,2).*weights(:,2).*tauNormalized(botTauNormalized:topTauNormalized,2)).*step;
                else
                    c=sum(data(botTable:topTable,2).*weights(botWeights:topWeights,2).*tauNormalized(botTauNormalized:topTauNormalized,2)).*step;
                end
            end
        end
        
        function processDatabaseSpectra(app, i, j, ~ ,~)
            
            if app.spectraRow == 7 && app.sourceRow == 3
                const=[3.73952397024951e-16, 0.0143878631423231];
                x=200:3000;
                y=(const(1)./(x.*10^-9).^5).*(1./(exp(const(2)./(app.Temperature.Value.*(x.*10^-9)))-1));
                y=y./max(y);
                spectralPlotting(app, (200:3000), y, 200, 3000, 0, max(y));
                app.ResultTable.Data=computeEnergyValues(app, x, y);
            elseif app.spectraRow == 8 && app.sourceRow == 4
                svdNormalized=app.LD.svdSpectras.Values{i}./max(app.LD.svdSpectras.Values{i});
                spectralPlotting(app, app.LD.svdSpectras.Wavelengths{i}, svdNormalized,...
                    min(app.LD.svdSpectras.Wavelengths{i}), max(app.LD.svdSpectras.Wavelengths{i}), 0, 1);
                computeEnergyValues(app, app.LD.svdSpectras.Wavelengths{i}, app.LD.svdSpectras.Values{i});
            else
                switch app.spectraRow
                    case 3
                        col=j;
                    case 4
                        col=j;
                    otherwise
                        col = str2double(app.LD.databaseSpectrum{app.spectraRow,2}{i,2}(j,2));
                end
                y = app.LD.spectralDatabaseData(:,col);
                spectralPlotting(app, (380:780), y, 380, 780, 0, 1);
                computeEnergyValues(app, (380:780), y);
                app.Label.Text="Column "+col;
            end
            
        end
        
        function spectralPlotting(app, x, y, wbot, wtop, vbot, vtop)
            app.UIAxes.XLim = [wbot wtop];
            app.UIAxes.YLim = [(vbot~=0)*vbot+(vbot==0)*0 (vtop~=0)*vtop+(vtop==0)*1];
            plot(app.UIAxes,x,y);
        end
        
        function disableI(app)
            app.SpinnerSource.Enable = 'off';
            app.SelectSpectrum.Enable = 'off';
            app.SelectSpecificSource.Enable = 'off';
            app.SelectSourceType.Enable = 'off';
            app.Temperature.Enable = 'off';
            app.BoxUVskinAndEye.Enable = 'off';
            app.BoxUVA.Enable = 'off';
            app.BoxBlueLight.Enable = 'off';
            app.BoxRetinaLr.Enable = 'off';
            app.BoxInfraRed.Enable = 'off';
            app.FieldDistance.Enable = 'off';
            app.FieldMedianDimension.Enable = 'off';
            app.FieldIlluminance.Enable = 'off';
            app.BoxAge.Enable = 'off';
            app.FieldAgeNumber.Enable = 'off';
        end
        
        function enableI(app)
            app.SpinnerSource.Enable = 'on';
            app.SelectSpectrum.Enable = 'on';
            app.SelectSpecificSource.Enable = 'on';
            app.SelectSourceType.Enable = 'on';
            app.Temperature.Enable = 'on';
            app.BoxUVskinAndEye.Enable = 'on';
            app.BoxUVA.Enable = 'on';
            app.BoxBlueLight.Enable = 'on';
            app.BoxRetinaLr.Enable = 'on';
            app.BoxInfraRed.Enable = 'on';
            app.FieldDistance.Enable = 'on';
            app.FieldMedianDimension.Enable = 'on';
            app.FieldIlluminance.Enable = 'on';
            app.BoxAge.Enable = 'on';
            app.FieldAgeNumber.Enable = 'on';
        end
        
        function R=computeEnergyValues(app, x, y)
            V=app.FieldDistance.Value;
            result(:,1)=x;
            result(:,2)=y;
            wavelengthStep=result(2,1)-result(1,1);
            
            if V==0.2 || V==0.5 || V==1 || V==2.5 || V==3 || V==4 || V==5 || V==6 || V==7 || V==8 || V==9 || V==10
                distanceTable=[0.2;0.5;1;2.5;3;4;5;6;7;8;9;10];
            else
                distanceTable=[V;0.2;0.5;1;2.5;3;4;5;6;7;8;9;10];
            end
            
            distanceArray=(V./distanceTable).^2;
            gammaArray=app.FieldMedianDimension.Value./distanceTable;
            omegaArray=pi.*gammaArray.^2./4;
            [E_s,E_uva,E_IR,E_b,L_b,L_r]=deal(NaN);
            
            if app.spectraRow == 8
                illuminance=(683*brdrIntegral(app,360,800,result,app.LD.lambdaFunction,wavelengthStep,0));
                K=1;
                irradianceArray=distanceArray.*illuminance;
                luminance=irradianceArray(1)/omegaArray(1);
            else
                illuminance=app.FieldIlluminance.Value;
                I=683*brdrIntegral(app,683,800,result,app.LD.lambdaFunction,wavelengthStep,0);
                K=illuminance/I;
                irradianceArray=distanceArray.*(I*K);
                luminance=irradianceArray(1)/omegaArray(1);
            end
            
            if illuminance ~= 500
                irradianceArray(end+1)=500;
                distanceTable(end+1)=sqrt(illuminance/500*V^2);
                distanceArray(end+1)=(V/distanceTable(end))^2;
                gammaArray(end+1)=app.FieldMedianDimension.Value./distanceTable(end);
                omegaArray(end+1)=pi*gammaArray(end)^2/4;
            end
            
            uvlambda=app.LD.dangerUVforSkinAndEye;
            uvAlambda=ones([86 1]);
            if app.BoxEyeFakic.Value
                lambdaLens=app.LD.dangerBlueLight(:,[1,3]);
            elseif app.BoxEyeAfakic.Value
                lambdaLens=app.LD.dangerBlueLight(:,[1,2]);
            elseif app.BoxEyePseudofakic.Value
                lambdaLens=app.LD.dangerBlueLight(:,[1,3]);
                selectedLensNumber=find(string(app.SelectPseudofakic.Items)==app.SelectPseudofakic.Value);
                
                if selectedLensNumber==length(app.SelectPseudofakic.Items)
                    lambdaLens(:,2)=lambdaLens(:,2).*importLensSpectra(app);
                    lensName=char(app.LD.lensesDatabase.Properties.VariableNames(1+selectedLensNumber));
                    if app.LD.lensesDatabase.Properties.CustomProperties.AreLensesGlasses(selectedLensNumber)==1
                        uvlambda(:,2)=uvlambda(:,2).*app.LD.lensesDatabase.(lensName)(1:201);
                        uvAlambda=uvAlambda.*app.LD.lensesDatabase.(lensName)(116:201);
                    end
                else
                    lensName=char(app.LD.lensesDatabase.Properties.VariableNames(1+selectedLensNumber));
                    lambdaLens(:,2)=lambdaLens(:,2).*app.LD.lensesDatabase.(lensName)(101:501);
                    if app.LD.lensesDatabase.Properties.CustomProperties.AreLensesGlasses(selectedLensNumber)==1
                        uvlambda(:,2)=uvlambda(:,2).*app.LD.lensesDatabase.(lensName)(1:201);
                        uvAlambda=uvAlambda.*app.LD.lensesDatabase.(lensName)(116:201);
                    end
                    
                end
            end
            
            if result(2,1)-result(1,1)~=result(end,1)-result(end-1,1)
                l=(result(:,1)-[result(1,1)-(result(2,1)-result(1,1));result(1:end-1,1)])==1;
                resultVisible=result(l,:);
                if isempty(resultVisible);resultVisible=0;wavelengthStepVisible=1;else;wavelengthStepVisible=resultVisible(2,1)-resultVisible(1,1);end
                resultInfra=result(~l,:);
                if isempty(resultInfra);resultInfra=0;wavelengthStepInfra=1;else;wavelengthStepInfra=resultInfra(2,1)-resultInfra(1,1);end
                rawE_IR=K*(brdrIntegral(app,780,1000,resultVisible,0,wavelengthStepVisible,0)+...
                    brdrIntegral(app,1000,3000,resultInfra,0,wavelengthStepInfra,0));
                rawL_rLong=K*(brdrIntegral(app,380,1000,resultVisible,app.LD.dangerForRetina,wavelengthStepVisible,1)+...
                    brdrIntegral(app,1000,1400,resultInfra,app.LD.dangerForRetina,wavelengthStepInfra,1));
                rawL_rShort=K*(brdrIntegral(app,780,1000,resultVisible,app.LD.dangerForRetina(401:1021,:),wavelengthStepVisible,1)+...
                    brdrIntegral(app,1000,1400,resultInfra,app.LD.dangerForRetina(401:1021,:),wavelengthStepInfra,1));
            else
                rawE_IR=K*brdrIntegral(app,780,3000,result,0,wavelengthStep,0);
                rawL_rLong=K*brdrIntegral(app,380,1400,result,app.LD.dangerForRetina,wavelengthStep,1);
                rawL_rShort=K*brdrIntegral(app,780,1400,result,app.LD.dangerForRetina(401:1021,:),wavelengthStep,1);
            end
            
            rawE_s=K*brdrIntegral(app,200,400,result,uvlambda,wavelengthStep,0);
            rawE_uva=K*brdrIntegral(app,315,400,result,[(315:400)',uvAlambda],wavelengthStep,0);
            rawL_b=K*brdrIntegral(app,300,700,result,lambdaLens,wavelengthStep,1);
            rawE_b=K*brdrIntegral(app,300,700,result,lambdaLens,wavelengthStep,1);
            domega=distanceArray(1)/omegaArray(1);
            rowsNumber=length(distanceTable);
            
            if app.BoxInfraRed.Value
                E_IR=distanceArray.*rawE_IR;
            else
                E_IR(1:rowsNumber,1)=NaN;
            end
            
            if app.BoxUVskinAndEye.Value
                E_s=distanceArray*rawE_s;
            else
                E_s(1:rowsNumber,1)=NaN;
            end
            t_maxUV=30./E_s;
            t_maxUV(t_maxUV>30000)=NaN;
            
            if app.BoxUVA.Value
                E_uva=distanceArray.*rawE_uva;
            else
                E_uva(1:rowsNumber,1)=NaN;
            end
            t_maxUVA=10000./E_uva;
            t_maxUVA(t_maxUVA>1000)=NaN;
            
            if app.BoxBlueLight.Value
                E_b=distanceArray.*rawE_b;
                E_b(gammaArray>0.011)=NaN;
                t_maxb=100./E_b;
                t_maxb(t_maxb>100)=NaN;
            else
                E_b(1:rowsNumber,1)=NaN;
            end
            
            if app.BoxBlueLight.Value
                L_b(1:rowsNumber,1)=domega.*rawL_b;
                L_b(gammaArray>0.1)=NaN;
                t_maxb(isnan(t_maxb))=1000000./L_b(1);
                t_maxb(t_maxb>10000)=NaN;
            else
                L_b(1:rowsNumber,1)=NaN;
                t_maxb(1:rowsNumber,1)=NaN;
            end
            
            if app.BoxRetinaLr.Value
                L_r(1:rowsNumber,1)=domega*((luminance<10)*(rawL_rShort)+(luminance>10)*(rawL_rLong));
            else
                L_r(1:rowsNumber,1)=NaN;
            end
            
            k=logical((gammaArray<0.0017)+(gammaArray>1.4));
            [E_s(k), t_maxUV(k), E_uva(k), t_maxUVA(k), E_IR(k), E_b(k), L_b(k), t_maxb(k), L_r(k)]=deal(NaN);
            
            if app.BoxEyeAfakic.Value; L_r(:)=NaN; end
            R=sortrows([distanceTable, irradianceArray, E_s, t_maxUV, E_uva, t_maxUVA, E_IR, E_b, L_b, t_maxb, L_r]);
            app.ResultTable.UserData=R;
            
            numberOfZeros=floor(log10(R));
            numberOfZeros(numberOfZeros==-Inf)=0.0000;
            printedValues=cell([rowsNumber 11]);
            if sum(abs(numberOfZeros(:,1))>3)==0
                printedValues(1:rowsNumber,1)=compose('%.1f',round(R(:,1),1,'significant'));
            else
                printedValues(1:rowsNumber,1)=compose('%.2e',round(R(:,1),2,'significant'));
            end
            
            if sum(abs(numberOfZeros(:,2))>4)==0
                printedValues(1:rowsNumber,2)=compose('%.3f',round(R(:,2),3,'significant'));
            else
                printedValues(1:rowsNumber,2)=compose('%.2e',round(R(:,2),2,'significant'));
            end
            
            if sum(abs(numberOfZeros(:,3))>4)==0
                printedValues(1:rowsNumber,3)=compose('%.3f',round(R(:,3),3,'significant'));
            else
                printedValues(1:rowsNumber,3)=compose('%.2e',round(R(:,3),2,'significant'));
            end
            
            if sum(abs(numberOfZeros(:,4))>4)==0
                printedValues(1:rowsNumber,4)=compose('%.3f',round(R(:,4),3,'significant'));
            else
                printedValues(1:rowsNumber,4)=compose('%.2e',round(R(:,4),2,'significant'));
            end
            
            if sum(abs(numberOfZeros(:,5))>4)==0
                printedValues(1:rowsNumber,5)=compose('%.3f',round(R(:,5),3,'significant'));
            else
                printedValues(1:rowsNumber,5)=compose('%.2e',round(R(:,5),2,'significant'));
            end
            
            if sum(abs(numberOfZeros(:,6))>4)==0
                printedValues(1:rowsNumber,6)=compose('%.3f',round(R(:,6),3,'significant'));
            else
                printedValues(1:rowsNumber,6)=compose('%.2e',round(R(:,6),2,'significant'));
            end
            
            if sum(abs(numberOfZeros(:,7))>4)==0
                printedValues(1:rowsNumber,7)=compose('%.3f',round(R(:,7),3,'significant'));
            else
                printedValues(1:rowsNumber,7)=compose('%.2e',round(R(:,7),2,'significant'));
            end
            
            if sum(abs(numberOfZeros(:,8))>4)==0
                printedValues(1:rowsNumber,8)=compose('%.3f',round(R(:,8),3,'significant'));
            else
                printedValues(1:rowsNumber,8)=compose('%.2e',round(R(:,8),2,'significant'));
            end
            
            if sum(abs(numberOfZeros(:,9))>4)==0
                printedValues(1:rowsNumber,9)=compose('%.3f',round(R(:,9),3,'significant'));
            else
                printedValues(1:rowsNumber,9)=compose('%.2e',round(R(:,9),2,'significant'));
            end
            
            if sum(abs(numberOfZeros(:,10))>4)==0
                printedValues(1:rowsNumber,10)=compose('%.3f',round(R(:,10),3,'significant'));
            else
                printedValues(1:rowsNumber,10)=compose('%.2e',round(R(:,10),2,'significant'));
            end
            
            if sum(abs(numberOfZeros(:,11))>4)==0
                printedValues(1:rowsNumber,11)=compose('%.3f',round(R(:,11),3,'significant'));
            else
                printedValues(1:rowsNumber,11)=compose('%.2e',round(R(:,11),2,'significant'));
            end
            app.ResultTable.Data=printedValues;


            app.FieldRG0Es.Value=V*sqrt(rawE_s/0.001);
            app.FieldRG0EUVA.Value=V*sqrt(rawE_uva/10);
            app.FieldRG0Eb.Value=V*sqrt(rawE_b);
            if luminance(1)<10
                app.FieldRG0Lr.Value=(4*V^2)/(pi*app.FieldMedianDimension.Value*6000)*rawL_rShort;
            else
                app.FieldRG0Lr.Value=(4*V^2)/(pi*app.FieldMedianDimension.Value*6000)*rawL_rLong;
            end
            findStyle(app,R,luminance,sortrows(gammaArray,'descend'))
        end
        
        function [wavelength,numAverage]=excelSpectraImport(app)
            [files,path]= uigetfile('MultiSelect','on','*.xls;*.xlsx');
            num=xlsread(path+app.slash+files);
            num(isnan(num(:,1)),:)=[];
            wavelength=num(1:1+max(num(:,1))-num(1,1),1);
            num=num(1:1+max(num(:,1))-num(1,1),end-9:end);
            numAverage=mean(num,2);
            numNormalized=numAverage./max(numAverage);
            spectralPlotting(app,wavelength,numNormalized,wavelength(1),wavelength(end),0,1);
            app.ResultTable.Data=computeEnergyValues(app, wavelength, numAverage);
        end
        
        function [resultWavelength,resultData] = datSpectraImport(app)
            [files,path]= uigetfile('MultiSelect','on','*.dat');
            if ischar(files); files=string(files); end
            N=length(files);
            resultVisible=zeros([1000 N]);
            resultInfra=zeros([400 N]);
            for i=1:N
                formatSpec = '%q%q%q%q%q%q%q%q%q%[^\n\r]';
                fileID = fopen(path+app.slash+files(i),'r');
                dataArray = textscan(fileID, formatSpec, 'Delimiter', {',','E'}, 'TextType', 'string',  'ReturnOnError', false);
                fclose(fileID);
                units=dataArray{1,2}(1);
                wavelengthRange=str2double([dataArray{1,4}(1) dataArray{1,5}(1)]);
                wavelengthStep=str2double(dataArray{1,6}(1));
                rng=(wavelengthRange(1,2)-wavelengthRange(1,1))/wavelengthStep;
                resultValues=str2double(dataArray{1,1}(2:rng+2));
                exponents=str2double(dataArray{1,2}(2:rng+2));
                lastRow=length(resultValues);
                if wavelengthRange(1)<1000
                    rangeVisible=(wavelengthRange(1,1):wavelengthStep:wavelengthRange(1,2))';
                    if units=="[W/(cm^2 nm)]"
                        resultVisible(1:lastRow,i)=(resultValues.*10.^exponents)*10000;
                    elseif units=="[W/(m^2 nm)]"
                        resultVisible(1:lastRow,i)=(resultValues.*10.^exponents);
                    end
                else
                    rangeInfra=(wavelengthRange(1,1):wavelengthStep:wavelengthRange(1,2))';
                    if units=="[W/(cm^2 nm)]"
                        resultInfra(1:lastRow,i)=(resultValues.*10.^exponents)*10000;
                    elseif units=="[W/(m^2 nm)]"
                        resultInfra(1:lastRow,i)=(resultValues.*10.^exponents);
                    end
                end
            end
            avgResultVisible=mean(resultVisible(:,(resultVisible(1,:)~=0)),2);
            avgResultInfra=mean(resultInfra(:,(resultInfra(1,:)~=0)),2);
            avgResultVisible(logical(isnan(avgResultVisible)+(avgResultVisible==0)))=[];
            avgResultInfra(logical(isnan(avgResultInfra)+(avgResultInfra==0)))=[];
            if isempty(avgResultInfra)
                resultData = avgResultVisible;
                resultWavelength = rangeVisible;
            elseif isempty(avgResultVisible)
                resultData = avgResultInfra;
                resultWavelength = rangeInfra;
            else
                resultData = [avgResultVisible;avgResultInfra];
                resultWavelength = [rangeVisible;rangeInfra];
            end
            
            numNormalized=resultData./max(resultData);
            spectralPlotting(app,resultWavelength,numNormalized,resultWavelength(1),resultWavelength(end),0,1);
            app.ResultTable.Data=computeEnergyValues(app, resultWavelength, resultData);
        end
        
        function [resultWavelength,resultData] = avantesSpectraImport(app)
            resultWavelength=app.BoxAge;
            resultData=0;
        end
        
        function f_clipboard(app)
            if app.BoxAge.Value; a=app.FieldAgeNumber.Value; else; a=""; end
            row1=[app.FieldDistance.Value, app.FieldMedianDimension.Value, app.FieldIlluminance.Value, a,...
                app.FieldRG0Es.Value, app.FieldRG0EUVA.Value, app.FieldRG0Eb.Value, app.FieldRG0Lr.Value, "", "", ""];
            row2=["", "", "", "", "", "", "", "", "", "", ""];
            d=[row1;row2;app.ResultTable.UserData];
            size_d = size(d);
            str = '';
            for i = 1:size_d(1)
                for j = 1:size_d(2)
                    if j == size_d(2)
                        str = sprintf('%s%f',str,d(i,j));
                    else
                        str = sprintf('%s%f\t',str,d(i,j));
                    end
                end
                str = sprintf('%s\n',str);
            end
            clipboard('copy',str);
            
        end
        
        function values=importLensSpectra(app)
            t=groot;
            figureSize=[364 109];
            left=floor((t.ScreenSize(3)-figureSize(1))/2);
            bottom=floor((t.ScreenSize(4)-figureSize(2))/2);
            fig=uifigure('Position',[left bottom figureSize(1) figureSize(2)],'Visible',"off");
            txt={'Vložený spektrum pro čočku musí být zložený z dvou stloupcu'; ' první budou nanometry 200-1000 a druhý budou hodnoty'};
            uilabel(fig,"HorizontalAlignment",'center',"Text",txt,"Position",[17 72 331 28]);
            uilabel(fig,"HorizontalAlignment",'right',"Text",'Jméno čočky',"Position",[16 42 76 22]);
            edtField = uieditfield(fig, 'text',"Position",[106 42 126 22]);
            labelLmp = uilabel(fig,"Position",[145 11 118 22],"Text",'Zlej počet nanometru');
            lmp = uilamp(fig,"Position",[120 12 20 20],"Color",[1 0 0]);
            b = uicheckbox(fig,'Position',[20 12 100 22],'Text',"Jsou to brýle?");
            uibutton(fig, 'push','Text',"Importovat čočku","Position",[242 42 107 22],...
                'ButtonPushedFcn', @(btn,event) importLen(app,b.Value));
            labelLmp.Visible = 'off';
            lmp.Visible = 'off';
            fig.Visible = 'on';
            uiwait(fig);


            function importLen(app, value)
                labelLmp.Visible = 'off';
                lmp.Visible = 'off';
                if edtField.Value == ""
                    labelLmp.Text = 'Prázdny název čočky';
                    labelLmp.Visible = 'on';
                    lmp.Visible = 'on';
                    return
                end
                [files,path]= uigetfile('MultiSelect','on','*.xls;*.xlsx');
                num=xlsread(path+app.slash+files);
                num(isnan(num(:,1)),:)=[];
                values=num(101:501,2);
                [m,n]=size(num);
                if 801~=m || 2~=n || sum([200:1000]'==num(:,1))==800
                    labelLmp.Text = 'Zlej počet nanometru';
                    labelLmp.Visible = 'on';
                    lmp.Visible = 'on';
                    return
                else
                    lensesDatabase=[app.LD.lensesDatabase,table(num(:,2))];
                    lensesDatabase.Properties.CustomProperties.AreLensesGlasses(end)=value;
                    lensesDatabase.Properties.VariableDescriptions{end}=edtField.Value;
                    lensesDatabase.Properties.VariableNames{end}=char("var"+length(lensesDatabase.Properties.VariableNames));
                    
                    save('LD.mat','lensesDatabase','-append');
                    delete(fig);
                    app.LD=load('LD.mat');
                    app.SelectPseudofakic.Items=[lensesDatabase.Properties.VariableDescriptions(2:end),"Přidat vlastní ..."];
                    app.SelectPseudofakic.Value=app.SelectPseudofakic.Items(end-1);
                end
            end
        end
        
        function findStyle(app,X,lum,gm)
            removeStyle(app.ResultTable)
            addStyle(app.ResultTable,uistyle('HorizontalAlignment','right'));
            styleBasicWhite=uistyle("BackgroundColor",'white');
            styleBasicGray=uistyle("BackgroundColor",[0.94,0.94,0.94]);
            styleRG0=uistyle("BackgroundColor",'cyan');
            styleRG1=uistyle("BackgroundColor",'green');
            styleRG2=uistyle("BackgroundColor",'yellow');
            styleRG3=uistyle("BackgroundColor",'red');
            
            
            column=3;
            r=3-(X(:,column)<0.03)-(X(:,column)<0.003)-(X(:,column)<0.001);
            row3=find(r==3);row2=find(r==2);row1=find(r==1);row0=find(r==0);
            if ~isempty(row3);addStyle(app.ResultTable,styleRG3,'cell',[row3,repelem(column,length(row3))']);end
            if ~isempty(row2);addStyle(app.ResultTable,styleRG2,'cell',[row2,repelem(column,length(row2))']);end
            if ~isempty(row1);addStyle(app.ResultTable,styleRG1,'cell',[row1,repelem(column,length(row1))']);end
            if ~isempty(row0);addStyle(app.ResultTable,styleRG0,'cell',[row0,repelem(column,length(row0))']);end
            
            column=5;
            r=3-(X(:,column)<100)-(X(:,column)<33)-(X(:,column)<10);
            row3=find(r==3);row2=find(r==2);row1=find(r==1);row0=find(r==0);
            if ~isempty(row3);addStyle(app.ResultTable,styleRG3,'cell',[row3,repelem(column,length(row3))']);end
            if ~isempty(row2);addStyle(app.ResultTable,styleRG2,'cell',[row2,repelem(column,length(row2))']);end
            if ~isempty(row1);addStyle(app.ResultTable,styleRG1,'cell',[row1,repelem(column,length(row1))']);end
            if ~isempty(row0);addStyle(app.ResultTable,styleRG0,'cell',[row0,repelem(column,length(row0))']);end
            
            column=7;
            r=3-(X(:,column)<3200)-(X(:,column)<570)-(X(:,column)<100);
            row3=find(r==3);row2=find(r==2);row1=find(r==1);row0=find(r==0);
            if ~isempty(row3);addStyle(app.ResultTable,styleRG3,'cell',[row3,repelem(column,length(row3))']);end
            if ~isempty(row2);addStyle(app.ResultTable,styleRG2,'cell',[row2,repelem(column,length(row2))']);end
            if ~isempty(row1);addStyle(app.ResultTable,styleRG1,'cell',[row1,repelem(column,length(row1))']);end
            if ~isempty(row0);addStyle(app.ResultTable,styleRG0,'cell',[row0,repelem(column,length(row0))']);end
            
            column=8;
            r=3-(X(:,column)<400)-(X(:,column)<1)-(X(:,column)<1);
            row3=find(r==3);row2=find(r==2);row1=find(r==1);row0=find(r==0);
            if ~isempty(row3);addStyle(app.ResultTable,styleRG3,'cell',[row3,repelem(column,length(row3))']);end
            if ~isempty(row2);addStyle(app.ResultTable,styleRG2,'cell',[row2,repelem(column,length(row2))']);end
            if ~isempty(row1);addStyle(app.ResultTable,styleRG1,'cell',[row1,repelem(column,length(row1))']);end
            if ~isempty(row0);addStyle(app.ResultTable,styleRG0,'cell',[row0,repelem(column,length(row0))']);end
            
            column=9;
            r=3-(X(:,column)<4000000)-(X(:,column)<11000)-(X(:,column)<100);
            row3=find(r==3);row2=find(r==2);row1=find(r==1);row0=find(r==0);
            if ~isempty(row3);addStyle(app.ResultTable,styleRG3,'cell',[row3,repelem(column,length(row3))']);end
            if ~isempty(row2);addStyle(app.ResultTable,styleRG2,'cell',[row2,repelem(column,length(row2))']);end
            if ~isempty(row1);addStyle(app.ResultTable,styleRG1,'cell',[row1,repelem(column,length(row1))']);end
            if ~isempty(row0);addStyle(app.ResultTable,styleRG0,'cell',[row0,repelem(column,length(row0))']);end
            
            column=11;
            if lum>10
                r=3-(X(:,column)<71000./gm)-(X(:,column)<2800./gm)-(X(:,column)<2800./gm);
                row3=find(r==3);row2=find(r==2);row0=find(r==0);
                if ~isempty(row3);addStyle(app.ResultTable,styleRG3,'cell',[row3,repelem(column,length(row3))']);end
                if ~isempty(row2);addStyle(app.ResultTable,styleRG2,'cell',[row2,repelem(column,length(row2))']);end
                if ~isempty(row0);addStyle(app.ResultTable,styleRG0,'cell',[row0,repelem(column,length(row0))']);end
            else
                r=3-(X(:,column)<6000./gm)*3;
                row3=find(r==3);row0=find(r==0);
                if ~isempty(row3);addStyle(app.ResultTable,styleRG3,'cell',[row3,repelem(column,length(row3))']);end
                if ~isempty(row0);addStyle(app.ResultTable,styleRG0,'cell',[row0,repelem(column,length(row0))']);end
            end
            
            [row,col]=find(isnan(X));
            addStyle(app.ResultTable,styleBasicWhite,'cell',[row(~~mod(row,2)),col(~~mod(row,2))]);
            addStyle(app.ResultTable,styleBasicGray,'cell',[row(~mod(row,2)),col(~mod(row,2))]);
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t=groot;
            
            size=app.UIFigure.Position(3:4);
            left=floor((t.ScreenSize(3)-size(1))/2);
            bottom=floor((t.ScreenSize(4)-size(2))/2);
            app.UIFigure.Position = [left bottom size(1) size(2)];
            app.LD=load('LD.mat');
            app.SelectPseudofakic.Items=[app.LD.lensesDatabase.Properties.VariableDescriptions(2:end),"Přidat vlastní ..."];
            app.spectraRow = 1;
            app.sourceRow = 1;
            processDatabaseSpectra(app, 1, 1, 0, 0);
            app.SelectSourceType.Items = string(app.LD.databaseSpectrum{app.spectraRow,2}(:,1)');
            app.SelectSpecificSource.Items = string(app.LD.databaseSpectrum{app.spectraRow,2}{1,2}(:,1)');
            if ispc
                app.slash = "\";
            else
                app.slash = "/";
            end
        end

        % Value changed function: SelectSpectrum
        function SelectSpectrumValueChanged(app, event)
            value = app.SelectSpectrum.Value;
            app.ButtonImport.Visible = 'off';
            app.SpinnerSource.Visible = 'off';
            app.SelectSpecificSource.Visible = 'on';
            app.Temperature.Visible = 'off';
            app.LabelTemperature.Visible = 'off';
            app.FieldIlluminance.Enable = 'on';
            app.SaveSpectra.Visible = 'off';
            app.DeleteSpectra.Visible = 'off';
            app.SelectSourceType.Items = "";
            app.sourceRow=1;
            switch value
                case string(app.SelectSpectrum.Items(1,3))
                    app.SpinnerSource.Visible = 'on';
                    app.SelectSpecificSource.Visible = 'off';
                    app.SpinnerSource.Value = 1;
                    app.SpinnerSource.Limits = [1 13];
                    app.sourceRow = 1;
                    app.spectraRow=3;
                    processDatabaseSpectra(app, 1, 169, 0, 0);
                case string(app.SelectSpectrum.Items(1,4))
                    app.SpinnerSource.Visible = 'on';
                    app.SelectSpecificSource.Visible = 'off';
                    app.SpinnerSource.Limits = [1 125];
                    app.SpinnerSource.Value = 1;
                    app.sourceRow = 1;
                    app.spectraRow=4;
                    processDatabaseSpectra(app, 1, 90, 0, 0);
                case string(app.SelectSpectrum.Items(1,8))
                    app.spectraRow=8;
                    app.SelectSpecificSource.Visible = 'off';
                    app.ButtonImport.Visible = 'on';
                    app.FieldIlluminance.Enable = 'off';
                otherwise
                    app.spectraRow=find((string(app.SelectSpectrum.Items)==value)==1);
                    processDatabaseSpectra(app, 1, 1, 0, 0);
            end
            app.SelectSourceType.Items = string(app.LD.databaseSpectrum{app.spectraRow,2}(:,1)');
            app.SelectSpecificSource.Items = string(app.LD.databaseSpectrum{app.spectraRow,2}{1,2}(:,1)');
        end

        % Value changed function: SelectSourceType
        function SelectSourceTypeValueChanged(app, event)
            value = app.SelectSourceType.Value;
            app.SelectSpecificSource.Visible = 'on';
            app.ButtonImport.Visible = 'off';
            app.Temperature.Visible = 'off';
            app.LabelTemperature.Visible = 'off';
            app.FieldIlluminance.Enable = 'on';
            app.SaveSpectra.Visible = 'off';
            app.DeleteSpectra.Visible = 'off';
            switch app.spectraRow
                case 8
                    if string(value) == app.SelectSourceType.Items(4)
                        app.sourceRow=4;
                        app.SelectSpecificSource.Items=app.LD.svdSpectras.Names(2:end);
                        processDatabaseSpectra(app,2,1,1,1);
                        app.DeleteSpectra.Visible = 'on';
                    else
                        app.SelectSpecificSource.Visible = 'off';
                        app.ButtonImport.Visible = 'on';
                        app.FieldIlluminance.Enable = 'off';
                    end
                case 3
                    switch value
                        case string(app.SelectSourceType.Items(1,1))
                            app.SelectSpecificSource.Visible = 'off';
                            app.SpinnerSource.Visible = 'on';
                            app.SpinnerSource.Limits = [1 125];
                            app.SpinnerSource.Value = 1;
                            app.sourceRow = 1;
                            processDatabaseSpectra(app, app.sourceRow, 169, 0, 0);
                            
                        case string(app.SelectSourceType.Items(1,2))
                            app.SelectSpecificSource.Visible = 'off';
                            app.SpinnerSource.Visible = 'on';
                            app.SpinnerSource.Limits = [1 4];
                            app.SpinnerSource.Value = 1;
                            app.sourceRow = 2;
                            processDatabaseSpectra(app, app.sourceRow, 294, 0, 0);
                    end
                    
                case 4
                    switch value
                        case string(app.SelectSourceType.Items(1,1))
                            app.SpinnerSource.Limits = [1 13];
                            app.SpinnerSource.Visible = 'on';
                            app.SelectSpecificSource.Visible = 'off';
                            app.SpinnerSource.Value = 1;
                            app.sourceRow = 1;
                            processDatabaseSpectra(app, app.sourceRow, 90, 0, 0);
                            
                        case string(app.SelectSourceType.Items(1,2))
                            app.SpinnerSource.Limits = [1 2];
                            app.SpinnerSource.Visible = 'on';
                            app.SelectSpecificSource.Visible = 'off';
                            app.SpinnerSource.Value = 1;
                            app.sourceRow = 2;
                            processDatabaseSpectra(app, app.sourceRow, 103, 0, 0);
                            
                        case string(app.SelectSourceType.Items(1,3))
                            app.SpinnerSource.Visible = 'off';
                            app.SelectSpecificSource.Visible = 'on';
                            app.sourceRow = 3;
                            processDatabaseSpectra(app, app.sourceRow, 105, 0, 0);
                            
                        case string(app.SelectSourceType.Items(1,4))
                            app.SpinnerSource.Visible = 'off';
                            app.SelectSpecificSource.Visible = 'on';
                            app.sourceRow = 4;
                            processDatabaseSpectra(app, app.sourceRow, 137, 0, 0);
                    end
                    app.SelectSpecificSource.Items = string(app.LD.databaseSpectrum{app.spectraRow,2}{app.sourceRow,2}(:,1)');
                    
                otherwise
                    if value == "Planckian Radiation"
                        app.SelectSpecificSource.Visible = 'off';
                        app.Temperature.Visible = 'on';
                        app.LabelTemperature.Visible = 'on';
                        app.sourceRow = 3;
                        processDatabaseSpectra(app, app.Temperature.Value, 1, 0, 0);
                    else
                        app.Temperature.Visible = 'off';
                        app.SelectSpecificSource.Visible = 'on';
                        app.SelectSpecificSource.Visible='on';
                        app.sourceRow = find((string(app.SelectSourceType.Items)==value)==1);
                        app.SelectSpecificSource.Items = string(app.LD.databaseSpectrum{app.spectraRow,2}{app.sourceRow,2}(:,1)');
                        processDatabaseSpectra(app, app.sourceRow, 1, 0, 0);
                    end
            end
        end

        % Value changed function: BoxAge, BoxBlueLight, 
        % BoxEyeAfakic, BoxEyeFakic, BoxEyePseudofakic, 
        % BoxInfraRed, BoxRetinaLr, BoxUVA, BoxUVskinAndEye, 
        % FieldDistance, FieldIlluminance, FieldMedianDimension, 
        % SelectPseudofakic, SelectSpecificSource
        function SelectSpecificSourceValueChanged(app, event)
            spinnerValue=app.SpinnerSource.Value;
            value = [app.BoxEyeFakic.Value, app.BoxEyeAfakic.Value, app.BoxEyePseudofakic.Value];
            if string(event.Source.Type)=="uidropdown" || string(event.Source.Type)=="uinumericeditfield"
                
            else
                switch event.Source.Text
                    case string(app.BoxEyeFakic.Text)
                        app.BoxEyeFakic.Value = 1;
                        app.SelectPseudofakic.Visible = 0;
                        app.BoxEyeAfakic.Value = 0;
                        app.BoxEyePseudofakic.Value =0;
                    case string(app.BoxEyeAfakic.Text)
                        app.BoxEyeFakic.Value = value(2)==0;
                        app.SelectPseudofakic.Visible = 0;
                        app.BoxEyePseudofakic.Value =0;
                    case string(app.BoxEyePseudofakic.Text)
                        app.BoxEyeFakic.Value = value(3)==0;
                        app.BoxEyeAfakic.Value = 0;
                        app.SelectPseudofakic.Visible = value(3);
                end
                drawnow
            end
            
            if app.spectraRow == 8
                if app.sourceRow == 4
                    l = 1+find(string(app.SelectSpecificSource.Items) == app.SelectSpecificSource.Value);
                    app.ResultTable.Data=computeEnergyValues(app, app.LD.svdSpectras.Wavelengths{l},app.LD.svdSpectras.Values{l});
                    valuesNormalized=app.LD.svdSpectras.Values{l}./max(app.LD.svdSpectras.Values{l});
                    spectralPlotting(app,app.LD.svdSpectras.Wavelengths{l},valuesNormalized,...
                        app.LD.svdSpectras.Wavelengths{l}(1),app.LD.svdSpectras.Wavelengths{l}(end),0,1);
                else
                    app.ResultTable.Data=computeEnergyValues(app, app.importedWavelength,app.importedValues);
                    valuesNormalized=app.importedValues./max(app.importedValues);
                    spectralPlotting(app,app.importedWavelength,valuesNormalized,...
                        app.importedWavelength(1),app.importedWavelength(end),...
                        valuesNormalized(1),valuesNormalized(end));
                end
            else
                switch app.spectraRow
                    case 3
                        if app.sourceRow == 1
                            processDatabaseSpectra(app, app.sourceRow, (spinnerValue+168), 0, 0);
                        else
                            processDatabaseSpectra(app, app.sourceRow, (spinnerValue+293), 0, 0);
                        end
                    case 4
                        if app.sourceRow == 1
                            processDatabaseSpectra(app, app.sourceRow, (spinnerValue+89), 0, 0);
                        else
                            processDatabaseSpectra(app, app.sourceRow, (spinnerValue+102), 0, 0);
                        end
                    otherwise
                        l = find(string(app.SelectSpecificSource.Items) == app.SelectSpecificSource.Value);
                        
                        processDatabaseSpectra(app, app.sourceRow, l, 0, 0);
                end
                
            end
            
        end

        % Value changing function: SpinnerSource
        function SpinnerSourceValueChanging(app, event)
            spinnerValue = event.Value;
            switch app.spectraRow
                case 3
                    if app.sourceRow == 1
                        processDatabaseSpectra(app, app.sourceRow, (spinnerValue+168), 0, 0);
                    else
                        processDatabaseSpectra(app, app.sourceRow, (spinnerValue+293), 0, 0);
                    end
                case 4
                    if app.sourceRow == 1
                        processDatabaseSpectra(app, app.sourceRow, (spinnerValue+89), 0, 0);
                    else
                        processDatabaseSpectra(app, app.sourceRow, (spinnerValue+102), 0, 0);
                    end
            end
        end

        % Value changed function: Temperature
        function TemperatureValueChanged(app, event)
            value = app.Temperature.Value;
            processDatabaseSpectra(app, value, 1, 0, 0);
        end

        % Value changed function: FieldAgeNumber
        function FieldAgeNumberValueChanged(app, event)
            if floor(app.FieldAgeNumber.Value)==app.FieldAgeNumber.Value
                switch app.FieldAgeNumber.Value
                    case 1
                        app.LabelAge.Text = "rok";
                    case {2,3,4}
                        app.LabelAge.Text = "roky";
                    otherwise
                        app.LabelAge.Text = "let";
                end
            else
                app.LabelAge.Text = "roku";
            end
            spinnerValue=app.SpinnerSource.Value;
            if app.spectraRow == 8
                app.ResultTable.Data=computeEnergyValues(app, app.importedWavelength,app.importedValues);
            else
                switch app.spectraRow
                    case 3
                        if app.sourceRow == 1
                            processDatabaseSpectra(app, app.sourceRow, (spinnerValue+168), 0, 0);
                        else
                            processDatabaseSpectra(app, app.sourceRow, (spinnerValue+293), 0, 0);
                        end
                    case 4
                        if app.sourceRow == 1
                            processDatabaseSpectra(app, app.sourceRow, (spinnerValue+89), 0, 0);
                        else
                            processDatabaseSpectra(app, app.sourceRow, (spinnerValue+102), 0, 0);
                        end
                    otherwise
                        l = find(string(app.SelectSpecificSource.Items) == app.SelectSpecificSource.Value);
                        
                        processDatabaseSpectra(app, app.sourceRow, l, 0, 0);
                end
                
            end
        end

        % Button pushed function: ButtonImport
        function ButtonImportPushed(app, event)
            switch string(app.SelectSourceType.Value)
                case string(app.SelectSourceType.Items(1,1))
                    [app.importedWavelength,app.importedValues]=datSpectraImport(app);
                case string(app.SelectSourceType.Items(1,2))
                    [app.importedWavelength,app.importedValues]=excelSpectraImport(app);
                case string(app.SelectSourceType.Items(1,3))
                    [app.importedWavelength,app.importedValues]=avantesSpectraImport(app);
            end
            app.SaveSpectra.Visible = 'on';
        end

        % Button pushed function: CopytoclipboardButton
        function CopytoclipboardButtonPushed(app, event)
            f_clipboard(app);
        end

        % Button pushed function: SaveSpectra
        function SaveSpectraButtonPushed(app, event)
            t=groot;
            size=[300 30];
            left=floor((t.ScreenSize(3)-size(1))/2);
            bottom=floor((t.ScreenSize(4)-size(2))/2);
            editFieldLength = 145;
            fig = uifigure("Name","Uložit načítané spektrum","Position",[left bottom size(1) size(2)]);
            uilabel(fig,"Text","Jméno spektra","Position",[5 5 80 22]);
            edt = uieditfield(fig,"Editable","on","Position",[85 5 editFieldLength 22]);
            uibutton(fig,"push","Text","Uložit","Position",[90+editFieldLength 5 50 22],...
                'ButtonPushedFcn', @(btn,event) saveSpectrum(app,edt.Value));
            function saveSpectrum(app, name)
                svdSpectras.Names=[app.LD.svdSpectras.Names, name];
                svdSpectras.Values=[app.LD.svdSpectras.Values,app.importedValues];
                svdSpectras.Wavelengths=[app.LD.svdSpectras.Wavelengths,app.importedWavelength];
                save("LD.mat",'svdSpectras','-append');
                delete(fig);
                app.LD=load('LD.mat');
            end
        end

        % Button pushed function: DeleteSpectra
        function DeleteSpectraButtonPushed(app, event)
            t=groot;
            size=[85+2*50 20+22*3];
            left=floor((t.ScreenSize(3)-size(1))/2);
            bottom=floor((t.ScreenSize(4)-size(2))/2);
            fig = uifigure("Position",[left bottom size(1) size(2)]);
            text = sprintf('%s\n%s',"Přejete si vymazat spektrum",string(app.SelectSpecificSource.Value));
            uilabel(fig,"Text",text,"Position",[5 35 size(1)-10 40],'HorizontalAlignment','center');
            uibutton(fig,"push","Text","Áno","Position",[40 5 50 22],...
                'ButtonPushedFcn', @(btn,event) deleteSpectra(app));
            uibutton(fig,"push","Text","Ne","Position",[95 5 50 22],...
                'ButtonPushedFcn', @(btn,event) delete(fig));
            function deleteSpectra(app)
                if app.sourceRow == 4 && app.spectraRow == 8
                    l = find(string(app.SelectSpecificSource.Items) == app.SelectSpecificSource.Value);
                    svdSpectras.Names=[app.LD.svdSpectras.Names(1:l),app.LD.svdSpectras.Names(l+2:end)];
                    svdSpectras.Values=[app.LD.svdSpectras.Values(1:l),app.LD.svdSpectras.Values(l+2:end)];
                    svdSpectras.Wavelengths=[app.LD.svdSpectras.Wavelengths(1:l),app.LD.svdSpectras.Wavelengths(l+2:end)];
                    save("LD.mat",'svdSpectras','-append');
                    delete(fig);
                    app.LD=load('LD.mat');
                    app.SelectSpecificSource.Items=svdSpectras.Names(2:end);
                    app.SelectSpecificSource.Value=svdSpectras.Names(2);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spektrální průběh')
            xlabel(app.UIAxes, 'λ [nm]')
            ylabel(app.UIAxes, 'Eλ [-]')
            app.UIAxes.XLim = [380 780];
            app.BoxUVskinAndEye = uicheckbox(app.UIFigure);
            app.BoxUVskinAndEye.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.BoxUVskinAndEye.Text = 'Nebezpečí UV záření pro oko a kůži Es';
            app.BoxUVskinAndEye.Value = true;
            app.BoxUVA = uicheckbox(app.UIFigure);
            app.BoxUVA.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.BoxUVA.Text = 'Nebezpečí UVA záření pro oko E UVA';
            app.BoxUVA.Value = true;
            app.BoxBlueLight = uicheckbox(app.UIFigure);
            app.BoxBlueLight.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.BoxBlueLight.Text = 'Nebezpečí modrého světla Eb x Lb';
            app.BoxBlueLight.Value = true;
            app.BoxRetinaLr = uicheckbox(app.UIFigure);
            app.BoxRetinaLr.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.BoxRetinaLr.Text = 'Nebezpečí tepelného poškození sítnice Lr';
            app.BoxRetinaLr.Value = true;
            app.BoxInfraRed = uicheckbox(app.UIFigure);
            app.BoxInfraRed.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.BoxInfraRed.Text = 'Nebezpečí tepelného záření pro oko E IR';
            app.BoxInfraRed.Value = true;
            app.LabelBoxSelection = uilabel(app.UIFigure);
            app.LabelBoxSelection.Text = 'Výběr hodnoceného rizika';
            app.VbrtypuzdrojeDropDownLabel = uilabel(app.UIFigure);
            app.VbrtypuzdrojeDropDownLabel.Text = 'Výběr  typu zdroje';
            app.SelectSpectrum = uidropdown(app.UIFigure);
            app.SelectSpectrum.Items = {'Žárovka', 'Výbojka', 'LED s luminoforem', 'LED bez luminoforu a vícečipové', 'Zářivka úzkopásmová', 'Zářivka širokopásmová', 'Jiné', 'Vlastní'};
            app.SelectSpectrum.ValueChangedFcn = createCallbackFcn(app, @SelectSpectrumValueChanged, true);
            app.SelectSpectrum.Value = 'Žárovka';
            app.ResultTable = uitable(app.UIFigure);
            app.ResultTable.ColumnName = {'r [m]'; 'E [lx]'; 'Es [W.m-2]'; 't max S [s]'; 'E UVA [W.m-2]'; 't max UVA [s]'; 'E IR [W.m-2]'; 'Eb [W.m-2]'; 'Lb [W.m-2.sr-1]'; 't max b [s]'; 'Lr [W.m-2.sr-1]'};
            app.ResultTable.RowName = {};
            app.VzdlenostEditFieldLabel = uilabel(app.UIFigure);
            app.VzdlenostEditFieldLabel.Text = 'Vzdálenost';
            app.FieldDistance = uieditfield(app.UIFigure, 'numeric');
            app.FieldDistance.LowerLimitInclusive = 'off';
            app.FieldDistance.Limits = [0 Inf];
            app.FieldDistance.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.FieldDistance.Value = 1;
            app.LabelDistance = uilabel(app.UIFigure);
            app.LabelDistance.Text = 'm';
            app.StednrozmrEditFieldLabel = uilabel(app.UIFigure);
            app.StednrozmrEditFieldLabel.Text = 'Střední rozměr';
            app.FieldMedianDimension = uieditfield(app.UIFigure, 'numeric');
            app.FieldMedianDimension.LowerLimitInclusive = 'off';
            app.FieldMedianDimension.Limits = [0 Inf];
            app.FieldMedianDimension.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.FieldMedianDimension.Value = 0.02;
            app.LabelMedianDimension = uilabel(app.UIFigure);
            app.LabelMedianDimension.Text = 'm';
            app.OsvtlenostEditFieldLabel = uilabel(app.UIFigure);
            app.OsvtlenostEditFieldLabel.Text = 'Osvětlenost';
            app.FieldIlluminance = uieditfield(app.UIFigure, 'numeric');
            app.FieldIlluminance.LowerLimitInclusive = 'off';
            app.FieldIlluminance.Limits = [0 Inf];
            app.FieldIlluminance.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.FieldIlluminance.Value = 500;
            app.lxLabel = uilabel(app.UIFigure);
            app.lxLabel.Text = 'lx';
            app.LabelSelectSource = uilabel(app.UIFigure);
            app.LabelSelectSource.Text = 'Výběr konkrétního zdroje';
            app.SelectSourceType = uidropdown(app.UIFigure);
            app.SelectSourceType.Items = {};
            app.SelectSourceType.ValueChangedFcn = createCallbackFcn(app, @SelectSourceTypeValueChanged, true);
            app.SelectSourceType.Value = {};
            app.SelectSpecificSource = uidropdown(app.UIFigure);
            app.SelectSpecificSource.Items = {};
            app.SelectSpecificSource.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.SelectSpecificSource.Value = {};
            app.SpinnerSource = uispinner(app.UIFigure);
            app.SpinnerSource.ValueChangingFcn = createCallbackFcn(app, @SpinnerSourceValueChanging, true);
            app.SpinnerSource.Limits = [1 125];
            app.SpinnerSource.ValueDisplayFormat = '%.0f';
            app.SpinnerSource.Visible = 'off';
            app.SpinnerSource.Value = 1;
            app.LabelTemperature = uilabel(app.UIFigure);
            app.LabelTemperature.Visible = 'off';
            app.LabelTemperature.Text = 'Teplota [K]';
            app.Temperature = uieditfield(app.UIFigure, 'numeric');
            app.Temperature.LowerLimitInclusive = 'off';
            app.Temperature.Limits = [0 Inf];
            app.Temperature.ValueChangedFcn = createCallbackFcn(app, @TemperatureValueChanged, true);
            app.Temperature.Visible = 'off';
            app.Temperature.Value = 6000;
            app.BoxAge = uicheckbox(app.UIFigure);
            app.BoxAge.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.BoxAge.Text = '';
            app.LabelAgeBox = uilabel(app.UIFigure);
            app.LabelAgeBox.Text = 'Věk';
            app.FieldAgeNumber = uieditfield(app.UIFigure, 'numeric');
            app.FieldAgeNumber.LowerLimitInclusive = 'off';
            app.FieldAgeNumber.Limits = [0 Inf];
            app.FieldAgeNumber.ValueChangedFcn = createCallbackFcn(app, @FieldAgeNumberValueChanged, true);
            app.FieldAgeNumber.Value = 20;
            app.LabelAge = uilabel(app.UIFigure);
            app.LabelAge.Text = 'roků';
            app.ButtonImport = uibutton(app.UIFigure, 'push');
            app.ButtonImport.ButtonPushedFcn = createCallbackFcn(app, @ButtonImportPushed, true);
            app.ButtonImport.Visible = 'off';
            app.ButtonImport.Text = 'Import';
            app.BoxEyeFakic = uicheckbox(app.UIFigure);
            app.BoxEyeFakic.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.BoxEyeFakic.Text = 'Fakické oko';
            app.BoxEyeFakic.Value = true;
            app.BoxEyeAfakic = uicheckbox(app.UIFigure);
            app.BoxEyeAfakic.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.BoxEyeAfakic.Text = 'Afakické oko';
            app.BoxEyePseudofakic = uicheckbox(app.UIFigure);
            app.BoxEyePseudofakic.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.BoxEyePseudofakic.Text = 'Pseudofakické oko';
            app.SelectPseudofakic = uidropdown(app.UIFigure);
            app.SelectPseudofakic.Items = {};
            app.SelectPseudofakic.ValueChangedFcn = createCallbackFcn(app, @SelectSpecificSourceValueChanged, true);
            app.SelectPseudofakic.Visible = 'off';
            app.SelectPseudofakic.Value = {};
            app.LabelRG0 = uilabel(app.UIFigure);
            app.LabelRG0.Text = {'Minimální bezpečná'; 'vzdálenost kde je RG0:'};
            app.LabelRG0Es = uilabel(app.UIFigure);
            app.LabelRG0Es.Text = 'pro Es';
            app.FieldRG0Es = uieditfield(app.UIFigure, 'numeric');
            app.FieldRG0Es.Editable = 'off';
            app.LabelRG0EUVA = uilabel(app.UIFigure);
            app.LabelRG0EUVA.Text = 'pro E UVA';
            app.FieldRG0EUVA = uieditfield(app.UIFigure, 'numeric');
            app.FieldRG0EUVA.Editable = 'off';
            app.LabelRG0Eb = uilabel(app.UIFigure);
            app.LabelRG0Eb.Text = 'pro Eb';
            app.FieldRG0Eb = uieditfield(app.UIFigure, 'numeric');
            app.FieldRG0Eb.Editable = 'off';
            app.LabelRG0Lr = uilabel(app.UIFigure);
            app.LabelRG0Lr.Text = 'pro Lr';
            app.FieldRG0Lr = uieditfield(app.UIFigure, 'numeric');
            app.FieldRG0Lr.Editable = 'off';
            app.CopytoclipboardButton = uibutton(app.UIFigure, 'push');
            app.CopytoclipboardButton.ButtonPushedFcn = createCallbackFcn(app, @CopytoclipboardButtonPushed, true);
            app.CopytoclipboardButton.Text = 'Copy to clipboard';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SaveSpectra = uibutton(app.UIFigure, 'push');
            app.SaveSpectra.ButtonPushedFcn = createCallbackFcn(app, @SaveSpectraButtonPushed, true);
            app.SaveSpectra.Visible = 'off';
            app.SaveSpectra.Text = {'Save Current Spectrum'; ''};
            app.DeleteSpectra = uibutton(app.UIFigure, 'push');
            app.DeleteSpectra.ButtonPushedFcn = createCallbackFcn(app, @DeleteSpectraButtonPushed, true);
            app.DeleteSpectra.Visible = 'off';
            app.DeleteSpectra.Text = 'Delete Spectrum';
            app.LabelSafeDistance_1 = uilabel(app.UIFigure);
            app.LabelSafeDistance_1.Text = 'm';
            app.LabelSafeDistance_2 = uilabel(app.UIFigure);
            app.LabelSafeDistance_2.Text = 'm';
            app.LabelSafeDistance_3 = uilabel(app.UIFigure);
            app.LabelSafeDistance_3.Text = 'm';
            app.LabelSafeDistance_4 = uilabel(app.UIFigure);
            app.LabelSafeDistance_4.Text = 'm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calc6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end