classdef light_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LightningTab                  matlab.ui.container.Tab
        L_Device                      matlab.ui.control.Lamp
        L_Files_Light                 matlab.ui.control.Lamp
        Execute_Light                 matlab.ui.control.Button
        Count_Light                   matlab.ui.control.Label
        Ex_Label_Light                matlab.ui.control.Label
        SelectmeasurementdeviceLabel  matlab.ui.control.Label
        Select_Device                 matlab.ui.control.DropDown
        Select_Button_Light           matlab.ui.control.Button
        SelectingfilesforprocessingEditFieldLabel  matlab.ui.control.Label
        Select_Label_Light            matlab.ui.control.EditField
        Lam                           matlab.ui.control.Lamp
        PhotochromTab                 matlab.ui.container.Tab
        Execute_Photo                 matlab.ui.control.Button
        Count_Photo                   matlab.ui.control.Label
        Ex_Label_Photo                matlab.ui.control.Label
        Select_Files_Photo            matlab.ui.control.Button
        SelectingfilesforprocessingEditFieldLabel_2  matlab.ui.control.Label
        Select_Label_Photo            matlab.ui.control.EditField
        L_Files_2                     matlab.ui.control.Lamp
        ReflectanceLabel              matlab.ui.control.Label
        Box_1nm                       matlab.ui.control.CheckBox
        Box_5nm                       matlab.ui.control.CheckBox
        Save_Label                    matlab.ui.control.Label
        Spec_Number                   matlab.ui.control.NumericEditField
        KSLabel                       matlab.ui.control.Label
        Box_Spec_W                    matlab.ui.control.CheckBox
        Box_Spec_Range                matlab.ui.control.CheckBox
        Box_KS_1nm                    matlab.ui.control.CheckBox
        Box_KS_5nm                    matlab.ui.control.CheckBox
        Spec_Range_Top                matlab.ui.control.NumericEditField
        Just                          matlab.ui.control.Label
        Spec_Range_Bot                matlab.ui.control.NumericEditField
        Box_Save_Together             matlab.ui.control.CheckBox
        Time_Range_Bot                matlab.ui.control.NumericEditField
        Just_2                        matlab.ui.control.Label
        Time_Range_Top                matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        CyclecountLabel               matlab.ui.control.Label
        UVonoffLabel                  matlab.ui.control.Label
        PhotochromeSliderLabel        matlab.ui.control.Label
        PhotochromeSlider             matlab.ui.control.Slider
    end


    properties (Access = private)
        files
        path = ' ';
        M
        nano
        index
        range
        pass
        
    end

    methods (Access = private)
    
        function check_OceanView(app)
            app.L_Files_Light.Visible = 'off';
            app.L_Device.Visible='off';
            if app.path == ' '
                app.L_Files_Light.Visible = 'on';
                app.pass=0;
            elseif string(app.Select_Device.Value) == 'Select'
                app.L_Device.Visible='on';
                app.pass=0;
            else
                app.pass=1;
            end
            
        end
        
        function OceanViewOptics(app)
          
            %If only one file is chosen the result is character string sadly
            if 1==ischar(app.files)
               N=1;
            else
                N = length(app.files);
            end
            app.Count_Light.Text = "Importing";
            drawnow
            %Creating table for storing and outputing the results I hope there won't be 
            %need for more than 1024 columns
            result_average = zeros([(N) app.M]);
            result_deviation = zeros([(N) app.M]);
            average_1nm=zeros([N length(app.nano)]);
            deviation_1nm=zeros([N length(app.nano)]);
            average_5nm=zeros([N+1 (((length(app.nano)-1)/5)+1)]);
            deviation_5nm=zeros([N+1 (((length(app.nano)-1)/5)+1)]);

            %Producing opts because it saves 30 seconds per operation  with same results
            if N==1
                file_path=string(fullfile(app.path,app.files));
            else
                file_path=string(fullfile(app.path,app.files(1,1)));
            end
            opts=detectImportOptions(file_path);
            opts.VariableTypes(1,:)={'char'};
            clear file_path

            %Core processing loop
            for i = 1:N
                app.Count_Light.Text = i+"/"+N;
                drawnow
             if N==1
                 file_path=string(fullfile(app.path,app.files));
              else
                 file_path=string(fullfile(app.path,app.files(1,i)));
             end
    
             %This makes the table. The cells are being read as a character string,
             %which are then transformed into numbers while removing unnecessary cells
             X = readtable(file_path,opts);
             X(:,2)=[];
             X(:,1)=[];
             X{:,:}=(regexprep(X{:,:},',','.'));
             A=str2double(table2cell(X));
             A(1,:)=[];
    
             %Just computing the average and standart deviation using 1/N-1 formula
             result_average(i,:)=mean(A);
             result_deviation(i,:)=std(A);
    
             clear X file_path A m n average standart_deviation
            end

            %Transforming from decimal nanometers to integer nanometers with step 1 nanometer
            for k=1:N
             for j=1:length(app.nano)
                 idx=app.index(1,j);
                 average_1nm(k,j)=(result_average(k,idx-2) - 12*result_average(k,idx-1) + ...
                       120*result_average(k,idx)-12*result_average(k,idx+1) + result_average(k,idx+2))/98;
                 deviation_1nm(k,j)=(result_deviation(k,idx-2) - 12*result_deviation(k,idx-1) +...
                     120*result_deviation(k,idx)-12*result_deviation(k,idx+1) + result_deviation(k,idx+2))/98;
    
              end
            end
            average_1nm=[app.nano;average_1nm];
            deviation_1nm=[app.nano;deviation_1nm];

            %Selecting every 5th nanometer to create smaller table
            average_5nm(:,1)=average_1nm(:,1);
            deviation_5nm(:,1)=deviation_1nm(:,1);
            for i=1:((length(app.nano)-1)/5)
                average_5nm(:,i+1)=average_1nm(:,i*5+1);
                deviation_5nm(:,i+1)=deviation_1nm(:,i*5+1);
            end


            %Finally outputting averaged raw data from the device
            names = ["" app.files];
            
            T_average=table(names',average_1nm);
            writetable(T_average,fullfile(app.path,"average values 1nm.txt"),'Delimiter','\t');
            
            T_deviation=table(names',deviation_1nm);
            writetable(T_deviation,fullfile(app.path,"standart deviation 1nm.txt"),'Delimiter','\t');

            T_average=table(names',average_5nm);
            writetable(T_average,fullfile(app.path,"average values 5nm.txt"),'Delimiter','\t');

            T_deviation=table(names',deviation_5nm);
            writetable(T_deviation,fullfile(app.path,"standart deviation 5nm.txt"),'Delimiter','\t');

            clear average_1nm average_5nm deviation_1nm deviation_5nm i opts names N nano path result_average result_deviation T_average T_deviation files j k index idx deviation average

        end
        
    end


    methods (Access = private)

        % Button pushed function: Execute_Light
        function Execute_Light_Pushed(app, event)
            app.Count_Light.Text = "Checking";
            drawnow
            check_OceanView(app)
            if app.pass == 0
                app.Lam.Visible='on';
                app.Count_Light.Text = "Checking failed";
                return
            end
            %Here starts the real show
            switch string(app.Select_Device.Value)
                case 'STS'
                    app.M = 1024;
                    app.nano=[185,186,187,188,189,190,191,192,193,194,195,196,197,198,199,200,201,202,203,204,205,206,207,208,209,210,211,212,213,214];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
                    app.index=[4,7,9,11,13,16,18,20,22,24,27,29,31,33,35,38,40,42,44,47,49,51,53,55,58,60,62,64,66,69];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
                    app.range= 481;
                    OceanViewOptics(app);
                case 'USB 2000'
                    app.M = 2048;
                    app.nano=[185,186,187,188,189,190,191,192,193,194,195,196,197,198,199,200,201,202,203,204,205,206,207,208,209,210,211,212,213,214];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
                    app.index=[14,17,20,22,25,28,30,33,36,38,41,44,47,49,52,55,57,60,63,66,68,71,74,76,79,82,85,87,90,93];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
                    app.range= 686;
                    OceanViewOptics(app);
                case 'Avantes 8 Dual'
                    app.Lam.Color = [0 0 1];
                case 'Avantes 6'
                    app.Lam.Color = [1 0 1];
            end
            app.Count_Light.Text = "Finished";
        end

        % Callback function
        function Execute_Photo_Pushed(app, event)
            
        end

        % Button pushed function: Select_Button_Light, 
        % Select_Files_Photo
        function Select_Pushed(app, event)
            [app.files,app.path]= uigetfile('MultiSelect','on','*.txt;*.tit');
            app.Select_Label_Light.Value = app.path;
            app.Select_Label_Photo.Value = app.path;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LightningTab = uitab(app.TabGroup);
            app.LightningTab.Title = 'Lightning';
            app.L_Device = uilamp(app.LightningTab);
            app.L_Device.Visible = 'off';
            app.L_Files_Light = uilamp(app.LightningTab);
            app.L_Files_Light.Visible = 'off';
            app.Execute_Light = uibutton(app.LightningTab, 'push');
            app.Execute_Light.ButtonPushedFcn = createCallbackFcn(app, @Execute_Light_Pushed, true);
            app.Execute_Light.Text = 'Execute';
            app.Count_Light = uilabel(app.LightningTab);
            app.Count_Light.Text = '';
            app.Ex_Label_Light = uilabel(app.LightningTab);
            app.Ex_Label_Light.Text = 'Execute processing of the files';
            app.SelectmeasurementdeviceLabel = uilabel(app.LightningTab);
            app.SelectmeasurementdeviceLabel.Text = 'Select measurement device';
            app.Select_Device = uidropdown(app.LightningTab);
            app.Select_Device.Items = {'Select', 'STS', 'USB 2000', 'Avantes 8 Dual', 'Avantes 6'};
            app.Select_Device.Value = 'Select';
            app.Select_Button_Light = uibutton(app.LightningTab, 'push');
            app.Select_Button_Light.ButtonPushedFcn = createCallbackFcn(app, @Select_Pushed, true);
            app.Select_Button_Light.Text = 'Select';
            app.SelectingfilesforprocessingEditFieldLabel = uilabel(app.LightningTab);
            app.SelectingfilesforprocessingEditFieldLabel.Text = 'Selecting files for processing';
            app.Select_Label_Light = uieditfield(app.LightningTab, 'text');
            app.Select_Label_Light.Editable = 'off';
            app.Select_Label_Light.Value = ' ';
            app.Lam = uilamp(app.LightningTab);
            app.PhotochromTab = uitab(app.TabGroup);
            app.PhotochromTab.Title = 'Photochrom';
            app.Execute_Photo = uibutton(app.PhotochromTab, 'push');
            app.Execute_Photo.Text = 'Execute';
            app.Count_Photo = uilabel(app.PhotochromTab);
            app.Count_Photo.Text = '';
            app.Ex_Label_Photo = uilabel(app.PhotochromTab);
            app.Ex_Label_Photo.Text = 'Execute processing of the files';
            app.Select_Files_Photo = uibutton(app.PhotochromTab, 'push');
            app.Select_Files_Photo.ButtonPushedFcn = createCallbackFcn(app, @Select_Pushed, true);
            app.Select_Files_Photo.Text = 'Select';
            app.SelectingfilesforprocessingEditFieldLabel_2 = uilabel(app.PhotochromTab);
            app.SelectingfilesforprocessingEditFieldLabel_2.Text = 'Selecting files for processing';
            app.Select_Label_Photo = uieditfield(app.PhotochromTab, 'text');
            app.Select_Label_Photo.Editable = 'off';
            app.Select_Label_Photo.Value = ' ';
            app.L_Files_2 = uilamp(app.PhotochromTab);
            app.L_Files_2.Visible = 'off';
            app.ReflectanceLabel = uilabel(app.PhotochromTab);
            app.ReflectanceLabel.Text = 'Reflectance';
            app.Box_1nm = uicheckbox(app.PhotochromTab);
            app.Box_1nm.Text = '1 nm timetable';
            app.Box_5nm = uicheckbox(app.PhotochromTab);
            app.Box_5nm.Text = '5 nm timetable';
            app.Box_5nm.Value = true;
            app.Save_Label = uilabel(app.PhotochromTab);
            app.Save_Label.Text = 'What to save';
            app.Spec_Number = uieditfield(app.PhotochromTab, 'numeric');
            app.Spec_Number.Limits = [380 780];
            app.Spec_Number.RoundFractionalValues = 'on';
            app.Spec_Number.ValueDisplayFormat = '%.0f';
            app.Spec_Number.Value = 380;
            app.KSLabel = uilabel(app.PhotochromTab);
            app.KSLabel.Text = 'K/S';
            app.Box_Spec_W = uicheckbox(app.PhotochromTab);
            app.Box_Spec_W.Text = 'Values fo specified wavelength';
            app.Box_Spec_W.Value = true;
            app.Box_Spec_Range = uicheckbox(app.PhotochromTab);
            app.Box_Spec_Range.Text = 'Values for specified range of wavelengths';
            app.Box_KS_1nm = uicheckbox(app.PhotochromTab);
            app.Box_KS_1nm.Text = '1 nm timetable';
            app.Box_KS_5nm = uicheckbox(app.PhotochromTab);
            app.Box_KS_5nm.Text = '5 nm timetable';
            app.Box_KS_5nm.Value = true;
            app.Spec_Range_Top = uieditfield(app.PhotochromTab, 'numeric');
            app.Spec_Range_Top.Limits = [380 780];
            app.Spec_Range_Top.RoundFractionalValues = 'on';
            app.Spec_Range_Top.ValueDisplayFormat = '%.0f';
            app.Spec_Range_Top.Value = 680;
            app.Just = uilabel(app.PhotochromTab);
            app.Just.Text = ':';
            app.Spec_Range_Bot = uieditfield(app.PhotochromTab, 'numeric');
            app.Spec_Range_Bot.Limits = [380 780];
            app.Spec_Range_Bot.RoundFractionalValues = 'on';
            app.Spec_Range_Bot.ValueDisplayFormat = '%.0f';
            app.Spec_Range_Bot.Value = 420;
            app.Box_Save_Together = uicheckbox(app.PhotochromTab);
            app.Box_Save_Together.Text = 'Save together';
            app.Box_Save_Together.Value = true;
            app.Time_Range_Bot = uieditfield(app.PhotochromTab, 'numeric');
            app.Time_Range_Bot.LowerLimitInclusive = 'off';
            app.Time_Range_Bot.UpperLimitInclusive = 'off';
            app.Time_Range_Bot.Limits = [0 Inf];
            app.Time_Range_Bot.RoundFractionalValues = 'on';
            app.Time_Range_Bot.ValueDisplayFormat = '%.0f';
            app.Time_Range_Bot.Value = 900;
            app.Just_2 = uilabel(app.PhotochromTab);
            app.Just_2.Text = ':';
            app.Time_Range_Top = uieditfield(app.PhotochromTab, 'numeric');
            app.Time_Range_Top.LowerLimitInclusive = 'off';
            app.Time_Range_Top.UpperLimitInclusive = 'off';
            app.Time_Range_Top.Limits = [0 Inf];
            app.Time_Range_Top.RoundFractionalValues = 'on';
            app.Time_Range_Top.ValueDisplayFormat = '%.0f';
            app.Time_Range_Top.Value = 150;
            app.EditField = uieditfield(app.PhotochromTab, 'numeric');
            app.EditField.Limits = [1 Inf];
            app.EditField.Value = 5;
            app.CyclecountLabel = uilabel(app.PhotochromTab);
            app.CyclecountLabel.Text = 'Cycle count';
            app.UVonoffLabel = uilabel(app.PhotochromTab);
            app.UVonoffLabel.Text = 'UV on/off';
            app.PhotochromeSliderLabel = uilabel(app.PhotochromTab);
            app.PhotochromeSliderLabel.Text = 'Photochrome';
            app.PhotochromeSlider = uislider(app.PhotochromTab);
            app.PhotochromeSlider.Limits = [2 3];
            app.PhotochromeSlider.MajorTicks = [2 3];
            app.PhotochromeSlider.MajorTickLabels = {'2', '3'};
            app.PhotochromeSlider.MinorTicks = [];
            app.PhotochromeSlider.Value = 2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = light_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end