classdef light_app_v_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LightningTab                  matlab.ui.container.Tab
        L_Device                      matlab.ui.control.Lamp
        L_Files_Light                 matlab.ui.control.Lamp
        Execute_Light                 matlab.ui.control.Button
        Count_Light                   matlab.ui.control.Label
        Ex_Label_Light                matlab.ui.control.Label
        SelectmeasurementdeviceLabel  matlab.ui.control.Label
        Select_Device                 matlab.ui.control.DropDown
        Select_Button_Light           matlab.ui.control.Button
        SelectingfilesforprocessingEditFieldLabel  matlab.ui.control.Label
        Select_Label_Light            matlab.ui.control.EditField
        Lam                           matlab.ui.control.Lamp
        PhotochromTab                 matlab.ui.container.Tab
        Execute_Photo                 matlab.ui.control.Button
        Count_Photo                   matlab.ui.control.Label
        Ex_Label_Photo                matlab.ui.control.Label
        Select_Files_Photo            matlab.ui.control.Button
        SelectingfilesforprocessingEditFieldLabel_2  matlab.ui.control.Label
        Select_Label_Photo            matlab.ui.control.EditField
        ReflectanceLabel              matlab.ui.control.Label
        Box_1nm                       matlab.ui.control.CheckBox
        Box_5nm                       matlab.ui.control.CheckBox
        Save_Label                    matlab.ui.control.Label
        Spec_Number                   matlab.ui.control.NumericEditField
        KSLabel                       matlab.ui.control.Label
        Box_Spec_KS                   matlab.ui.control.CheckBox
        Box_Spec_Range                matlab.ui.control.CheckBox
        Box_KS_1nm                    matlab.ui.control.CheckBox
        Box_KS_5nm                    matlab.ui.control.CheckBox
        Spec_Range_Top                matlab.ui.control.NumericEditField
        Just                          matlab.ui.control.Label
        Spec_Range_Bot                matlab.ui.control.NumericEditField
        Box_Save_Together             matlab.ui.control.CheckBox
        Time_Range_Bot                matlab.ui.control.NumericEditField
        Just_2                        matlab.ui.control.Label
        Time_Range_Top                matlab.ui.control.NumericEditField
        Cycle_Count                   matlab.ui.control.NumericEditField
        CyclecountLabel               matlab.ui.control.Label
        UVonoffLabel                  matlab.ui.control.Label
        DeviceDropDownLabel           matlab.ui.control.Label
        Device_Photo                  matlab.ui.control.DropDown
        Force_Stop                    matlab.ui.control.StateButton
        Time_Count_Photo              matlab.ui.control.Label
        Box_Analysis                  matlab.ui.control.CheckBox
        OnlyanalysisButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        files
        path = ' ';
        M
        nano
        index
        range
        pass
        rounding
        cycle
        nm
        nm_range
        exp
        dec
    end
    
    methods (Access = private)
        
        function Check_OceanView(app)
            app.L_Files_Light.Visible = 'off';
            app.L_Device.Visible='off';
            if app.path == ' '
                app.L_Files_Light.Visible = 'on';
                app.pass=0;
            elseif string(app.Select_Device.Value) == {'Select'}
                app.L_Device.Visible='on';
                app.pass=0;
            else
                app.pass=1;
            end
            
        end
        
        function OceanViewOptics(app)
            
            %If only one file is chosen the result is character string sadly
            if 1==ischar(app.files)
                N=1;
            else
                N = length(app.files);
            end
            app.Count_Light.Text = "Importing";
            drawnow
            %Creating table for storing and outputing the results I hope there won't be
            %need for more than 1024 columns
            result_average = zeros([(N) app.M]);
            result_deviation = zeros([(N) app.M]);
            average_1nm=zeros([N length(app.nano)]);
            deviation_1nm=zeros([N length(app.nano)]);
            average_5nm=zeros([N+1 (((length(app.nano)-1)/5)+1)]);
            deviation_5nm=zeros([N+1 (((length(app.nano)-1)/5)+1)]);
            
            %Producing opts because it saves 30 seconds per operation  with same results
            if N==1
                file_path=app.path+"/"+app.files;
            else
                file_path=app.path+"/"+app.files(1,1);
            end
            opts=detectImportOptions(file_path);
            opts.VariableTypes(1,:)={'char'};
            clear file_path
            
            %Core processing loop
            for i = 1:N
                app.Count_Light.Text = i+"/"+N;
                drawnow
                if N==1
                    file_path=app.path+"/"+app.files;
                else
                    file_path=app.path+"/"+app.files(1,i);
                end
                
                %This makes the table. The cells are being read as a character string,
                %which are then transformed into numbers while removing unnecessary cells
                X = readtable(file_path,opts);
                X(:,2)=[];
                X(:,1)=[];
                X{:,:}=(regexprep(X{:,:},',','.'));
                A=str2double(table2cell(X));
                A(1,:)=[];
                
                %Just computing the average and standart deviation using 1/N-1 formula
                result_average(i,:)=mean(A);
                result_deviation(i,:)=std(A);
                
                clear X file_path A m n average standart_deviation
            end
            
            %Transforming from decimal nanometers to integer nanometers with step 1 nanometer
            for k=1:N
                for j=1:length(app.nano)
                    idx=app.index(1,j);
                    average_1nm(k,j)=(result_average(k,idx-2) - 12*result_average(k,idx-1) + ...
                        120*result_average(k,idx)-12*result_average(k,idx+1) + result_average(k,idx+2))/98;
                    deviation_1nm(k,j)=(result_deviation(k,idx-2) - 12*result_deviation(k,idx-1) +...
                        120*result_deviation(k,idx)-12*result_deviation(k,idx+1) + result_deviation(k,idx+2))/98;
                    
                end
            end
            average_1nm=[app.nano;average_1nm];
            deviation_1nm=[app.nano;deviation_1nm];
            
            %Selecting every 5th nanometer to create smaller table
            average_5nm(:,1)=average_1nm(:,1);
            deviation_5nm(:,1)=deviation_1nm(:,1);
            for i=1:((length(app.nano)-1)/5)
                average_5nm(:,i+1)=average_1nm(:,i*5+1);
                deviation_5nm(:,i+1)=deviation_1nm(:,i*5+1);
            end
            
            
            %Finally outputting averaged raw data from the device
            names = ["" app.files];
            
            T_average=table(names',round(average_1nm,app.rounding));
            writetable(T_average,app.path+"/"+"average values 1nm.txt",'Delimiter','\t');
            
            T_deviation=table(names',round(deviation_1nm,app.rounding));
            writetable(T_deviation,app.path+"/"+"standart deviation 1nm.txt",'Delimiter','\t');
            
            T_average=table(names',round(average_5nm,app.rounding));
            writetable(T_average,app.path+"/"+"average values 5nm.txt",'Delimiter','\t');
            
            T_deviation=table(names',round(deviation_5nm,app.rounding));
            writetable(T_deviation,app.path+"/"+"standart deviation 5nm.txt",'Delimiter','\t');
            
            clear average_1nm average_5nm deviation_1nm deviation_5nm i opts names N nano path result_average result_deviation T_average T_deviation files j k index idx deviation average
            
        end
        
        function Check_Photochrome(app)
            if app.path==' '
                app.pass=0;
            else
                app.pass=1;
            end
        end


        function Initialize(app)
            app.rounding = 4;
            app.cycle = app.Cycle_Count.Value;
            app.nm = app.Spec_Number.Value;
            app.nm_range = [app.Spec_Range_Bot.Value app.Spec_Range_Top.Value];
            app.exp = app.Time_Range_Top.Value;
            app.dec = app.Time_Range_Bot.Value;
            
        end
        
        function Enable_All_Photo(app)
            if app.Box_Spec_Range.Value == true
                app.Spec_Range_Top.Enable='on';
                app.Spec_Range_Bot.Enable='on';
                app.Just.Enable='on';
            else
                app.Spec_Range_Top.Enable='off';
                app.Spec_Range_Bot.Enable='off';
                app.Just.Enable='off';
            end
            
            if app.Box_Spec_KS.Value == true
                app.Spec_Number.Enable='on';
                app.Box_Save_Together.Enable='on';
            else
                app.Spec_Number.Enable='off';
                app.Box_Save_Together.Enable='off';
            end
            
            app.Execute_Photo.Enable='on';
            app.Time_Range_Bot.Enable='on';
            app.Time_Range_Top.Enable='on';
            app.Just_2.Enable='on';
            app.Cycle_Count.Enable='on';
            app.Device_Photo.Enable='on';
            app.Box_1nm.Enable='on';
            app.Box_5nm.Enable='on';
            app.Box_Spec_KS.Enable='on';
            app.Box_Spec_Range.Enable='on';
            app.Box_KS_1nm.Enable='on';
            app.Box_KS_5nm.Enable='on';
            app.Force_Stop.Visible='off';
            app.Box_Analysis.Enable='on';
            app.OnlyanalysisButton.Enable = 'on';
        end
        
        function Disable_All_Photo(app)
            app.Box_Analysis.Enable='off';
            app.Execute_Photo.Enable='off';
            app.Spec_Range_Top.Enable='off';
            app.Spec_Range_Bot.Enable='off';
            app.Just.Enable='off';
            app.Box_Save_Together.Enable='off';
            app.Time_Range_Bot.Enable='off';
            app.Time_Range_Top.Enable='off';
            app.Just_2.Enable='off';
            app.Cycle_Count.Enable='off';
            app.Device_Photo.Enable='off';
            app.Box_1nm.Enable='off';
            app.Box_5nm.Enable='off';
            app.Box_Spec_KS.Enable='off';
            app.Box_Spec_Range.Enable='off';
            app.Box_KS_1nm.Enable='off';
            app.Box_KS_5nm.Enable='off';
            app.Spec_Number.Enable='off';
            app.Force_Stop.Value=false;
            app.OnlyanalysisButton.Enable = 'off';
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: Execute_Light
        function Execute_Light_Pushed(app, event)
            app.Count_Light.Text = "Checking";
            drawnow
            Check_OceanView(app)
            if app.pass == 0
                app.Lam.Visible='on';
                app.Count_Light.Text = "Checking failed";
                return
            end
            %Here starts the real show
            switch string(app.Select_Device.Value)
                case 'STS'
                    app.M = 1024;
                    app.nano=[185,186,187,188,189,190,191,192,193,194,195,196,197,198,199,200,201,202,203,204,205,206,207,208,209,210,211,212,213,214];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
                    app.index=[4,7,9,11,13,16,18,20,22,24,27,29,31,33,35,38,40,42,44,47,49,51,53,55,58,60,62,64,66,69];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
                    app.range= 481;
                    OceanViewOptics(app);
                case 'USB 2000'
                    app.M = 2048;
                    app.nano=[185,186,187,188,189,190,191,192,193,194,195,196,197,198,199,200,201,202,203,204,205,206,207,208,209,210,211,212,213,214];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
                    app.index=[14,17,20,22,25,28,30,33,36,38,41,44,47,49,52,55,57,60,63,66,68,71,74,76,79,82,85,87,90,93];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
                    app.range= 686;
                    OceanViewOptics(app);
                case 'Avantes 8 Dual'
                    app.Lam.Color = [0 0 1];
                case 'Avantes 6'
                    app.Lam.Color = [1 0 1];
            end
            app.Count_Light.Text = "Finished";
        end

        % Button pushed function: Execute_Photo
        function Execute_Photo_Pushed(app, event)
            Disable_All_Photo(app);
            app.Count_Photo.Text = "Checking";
            Initialize(app);
            Check_Photochrome(app);
            if app.pass == 0
                app.Count_Photo.Text = "Checking failed";
                Enable_All_Photo(app);
                return
            end
            app.Force_Stop.Visible='on';
            
            %If only one file is chosen the result is character string sadly
            if 1==ischar(app.files)
                N=1;
            else
                N = length(app.files);
            end
            
            %{
            app.Count_Photo.Text = "Finding opts";
            drawnow
            %Producing opts because it saves time per operation  with same results
            if N==1
                file_path=app.path+"/"+app.files;
            else
                file_path=app.path+"/"+app.files(1,1);
            end
            tic;
            opts=detectImportOptions(file_path);
            opts.VariableTypes(1,:)={'char'};
            clear file_path
            app.Count_Photo.Text = "Opts done";
            time_count=toc;
            app.Time_Count_Photo.Text = sprintf('%s\n%s',"Finding opts took ", round(time_count,2)+" seconds");
            %}
            
            
            app.index=[3,6,9,12,14,17,20,23,25,28,31,34,37,39,42,45,48,50,53,56,59,62,64,67,70,73,76,78,81,84];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
            app.nano=[380,381,382,383,384,385,386,387,388,389,390,391,392,393,394,395,396,397,398,399,400,401,402,403,404,405,406,407,408,409];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


            %Generating variable names
            drawnow
            if N==1
                v={'Measurement' strrep(strrep(strrep(app.files,' ',''),'.txt',''),'-','')};
            else
                v=['Measurement' strrep(strrep(strrep(app.files,' ',''),'.txt',''),'-','')];
            end
            
            
            %I though that we need to output time row as well, maybe implement later as a feature
            
            %time_output_low=dec/2;
            %time_output_high=dec+exp/2;
            
            if app.Device_Photo.Value == "Photochrome 2"
                table_length=(app.cycle * (app.exp + app.dec) + app.dec + 20);
            elseif app.Device_Photo.Value == "Photochrome 3"
                table_length=(app.cycle * (app.exp + app.dec) + 20);
            end
            %Result table for one wavelength
            
            together_result_ks=zeros([table_length N]);
            %together_result_w=zeros([table_length N]);
            
            %Core processing loop
            for i = 1:N
                if app.Force_Stop.Value==true
                    Enable_All_Photo(app);
                    break
                end
                tic
                app.Count_Photo.Text ="Importing file "+i+"/"+N;
                drawnow
                if N==1
                    file_path=app.path+"/"+app.files;
                    name=app.files;
                else
                    file_path=app.path+"/"+app.files(1,i);
                    name=app.files(1,i);
                end
                
                %{
                This makes the table. The cells are being read as a character string,
                which are then transformed into numbers while removing unnecessary cells
                Also this is way faster than readtable function LOL
                %}
                X_imp = textscan(fopen(file_path),'%s','Delimiter','\t','TextType','string');
                B=reshape(strrep((X_imp{1,1}(14:end)),',','.'),757,[]).';
                clear X_imp
                B(1,:)=[];
                B(:,1:2)=[];
                siz = [length(B) 755];
                cs = deblank(B(:)); 
                clear B
                idx = true(siz(1,1)*siz(1,2),1); 
                A = NaN(siz);
                clear siz
                A(idx) = sscanf(sprintf('%s#', cs{:}), '%g#');
                clear idx cs
                [p,~]=size(A);
                
                time_count=toc;
                app.Time_Count_Photo.Text = sprintf('%s\n%s',"Importing table "+i+"/"+N+" took ", round(time_count,2)+" seconds");
                app.Count_Photo.Text ="Computing table "+i+"/"+N;
                drawnow
                %Creating result of interpolation
                result=zeros([p 401]);
                
                %Interpolation
                for k=1:p
                    for l=1:259
                        idx=app.index(1,l);
                        result(k,l+41)=(A(k,idx-2) - 12*A(k,idx-1) + 120*A(k,idx)...
                            -12*A(k,idx+1) + A(k,idx+2))/98;
                    end
                end
                clear A
                %Expanding to 380 from 421
                for j=1:41
                    result(:,j)=result(:,42);
                end
                %expanding from 679 to 780
                for j=301:401
                    result(:,j)=result(:,300);
                end
                
                %Computing K/S values
                ks_1nm=result;
                for k=1:p
                    for j=1:401
                        B=ks_1nm(k,j)/100;
                        ks_1nm(k,j)=(((1-B)^2)/(2*B));
                    end
                end
                
                %Selecting every 5th nanometer
                result_1nm=([app.nano;result]);
                ks_1nm=([app.nano;ks_1nm]);
                result_5nm=result_1nm(:,1);
                ks_5nm=ks_1nm(:,1);
                
                
                for j=1:80
                    result_5nm(:,j+1)=result_1nm(:,j*5+1);
                    ks_5nm(:,j+1)=ks_1nm(:,j*5+1);
                end
                
                %Creating timetables for export
                time=(0:p)';
                result_1nm=([time result_1nm]);
                ks_1nm=([time ks_1nm]);
                result_5nm=([time result_5nm]);
                ks_5nm=([time ks_5nm]);
                
                
                subplot(2,2,1);
                title('Reflectance in minimum');
                plot(result_1nm(app.dec/2,2:end))
                subplot(2,2,2);
                title('Reflectance in maximum');
                plot(result_1nm(app.dec+app.exp/2,2:end))
                subplot(2,2,[3,4]);
                title("Wavelength in "+app.nm+"th nanometer");
                plot(ks_1nm(2:end,app.nm-379))


                %Saving
                if app.Box_1nm.Value==true
                    T_result_1nm=table(round(result_1nm,app.rounding));
                    writetable(T_result_1nm,app.path+"/1 nm result "+name,'Delimiter','\t');
                    clear T_result_1nm
                end
                
                if app.Box_5nm.Value==true
                    T_result_5nm=table(round(result_5nm,app.rounding));
                    writetable(T_result_5nm,app.path+"/5 nm result "+name,'Delimiter','\t');
                    clear T_result_5nm
                end
                
                if app.Box_KS_1nm.Value==true
                    T_ks_1nm=table(round(ks_1nm,app.rounding));
                    writetable(T_ks_1nm,app.path+"/KS 1nm "+name,'Delimiter','\t');
                    clear T_ks_1nm
                end
                
                if app.Box_KS_5nm.Value==true
                    T_ks_5nm=table(round(ks_5nm,app.rounding));
                    writetable(T_ks_5nm,app.path+"/KS 5nm "+name,'Delimiter','\t');
                    clear T_ks_5nm
                end
                
                %{
                T_nanometer=table(time,round(result_1nm(:,app.nm-378),app.rounding));
                writetable(T_nanometer,app.path+"/"+app.nm+" nanometer Reflectance "+name,'Delimiter','\t');
                clear T_nanometer
                %}
                
                if (app.Box_Spec_KS.Value == true) && (app.Box_Save_Together.Value == false)
                    T_KS_nanometer=table(time,round(ks_1nm(:,app.nm-378),app.rounding));
                    writetable(T_KS_nanometer,app.path+"/"+app.nm+" nanometer KS "+name,'Delimiter','\t');
                    clear T_KS_nanometer
                end
                
                %{
                T_nanometer_range=table(time,round(result_1nm(:,app.nm_range(1,1)-378:app.nm_range(1,2)-378),app.rounding));
                writetable(T_nanometer_range,app.path+"/"+app.nm_range(1,1)+" : "+app.nm_range(1,2)+" nanometer Reflectance "+name,'Delimiter','\t');
                clear T_nanometer_range
                %}
                
                if app.Box_Spec_Range.Value == true
                    T_KS_nanometer_range=table(time,round(ks_1nm(:,app.nm_range(1,1)-378:app.nm_range(1,2)-378),app.rounding));
                    writetable(T_KS_nanometer_range,app.path+"/"+app.nm_range(1,1)+" : "+app.nm_range(1,2)+" nanometer KS "+name,'Delimiter','\t');
                    clear T_KS_nanometer_range
                end
                
                clear B file_path j k ks_5nm l name p result result_5nm time
                
                %Output together n-th nanometer
                
                %together_result_w(:,i)=[result_1nm(:,app.nm-378);zeros(table_length-height(table(result_1nm)),1)];    line 365
                together_result_ks(:,i)=[ks_1nm(:,app.nm-378);zeros(table_length-height(table(ks_1nm)),1)];
                clear result_1nm ks_1nm
                
            end


            if (app.Box_Spec_KS.Value == true) && (app.Box_Save_Together.Value == true)
                %{
                T_concat_w=splitvars(table([(0:table_length-1)' round(together_result_w,app.rounding)]));
                T_concat_w.Properties.VariableNames = v;
                writetable(T_concat_w,app.path+"/"+"Refl nanometer "+ app.nm,'Delimiter','\t');
                %}
                T_concat_ks=splitvars(table([(0:table_length-1)' round(together_result_ks,app.rounding)]));
                T_concat_ks.Properties.VariableNames = v;
                writetable(T_concat_ks,app.path+"/"+"KS nanometer "+ app.nm,'Delimiter','\t');
                
            end
            
            clear i time_output_high time_output_low idx index nano nm nm_range opts nm nm_range T_concat_ks T_concat_w table_length v together_result_w
            
            if app.Box_Analysis.Value==true
                app.Count_Photo.Text ="Analysis";
                
                table_head={"One phase exponential fitting";"Best-fit values";"Span";"K";"Plateau";"Half Life";"Tau";"Y0";...
                    "95% confidence bounds";"Span";"";"K";"";"Plateau";"";"Half life";"";"Tau";"";"Y0";"";...
                    "Goodness of Fit";"Degrees of freedom";"R squared";"Sum of Squares";"Root mean squared";"Number of Y values"};
                
                prism_column_exposure=string(zeros([27 app.cycle]));
                prism_column_decay=string(zeros([27 app.cycle]));
                
                
                x=1:app.cycle;
                
                A_exposure=zeros([app.exp-5 app.cycle]);
                A_decay=zeros([app.dec-5 app.cycle]);
                warning('off','MATLAB:xlswrite:AddSheet');
                for k=1:N
                    [row,~]=find(together_result_ks(2:(app.dec+20),k)>1);
                    idx=row(1)+10;
                    for i=1:app.cycle
                        while 0.1<together_result_ks(idx,k)-together_result_ks(idx-1,k) || together_result_ks(idx,k)>1
                            idx=idx-1;
                        end
                        exposure_mean=mean(together_result_ks(idx-400:idx-100,k));
                        A_exposure(:,i)=together_result_ks(idx:idx+app.exp-6,k);
                        
                        x(i)=((exposure_mean-A_exposure(2,i))/(A_exposure(3,i)-A_exposure(2,i)))+1;
                        
                        idx=idx+app.exp+5;
                        
                        while 0.01<together_result_ks(idx-1,k)-together_result_ks(idx,k) || together_result_ks(idx,k)<together_result_ks(idx-2,k)
                            idx=idx-1;
                        end
                        A_decay(:,i)=together_result_ks(idx:idx+app.dec-6,k);
                        idx=idx+app.dec+5;
                    end
                    
                    clear idx exposure_mean row
                    %Exposure
                    %Finding coefficients
                    cca_a=together_result_ks(app.dec+app.exp/2,k);
                    cca_b=0.1;
                    cca_c_exp=together_result_ks(app.dec+app.exp/2,k);
                    cca_c_dec=together_result_ks(app.dec/2,k);
                    for i=1:app.cycle
                        [F,F1,F2]=fit([x(1,i);(1:app.exp-6)'],A_exposure(1:end,i),'a*exp(-b*x)+c','Start',[-cca_a cca_b cca_c_exp]);
                        %Creating output table
                        coefficients=[F.a F.b F.c log(2)/F.b 1/F.b F.a+F.c];
                        bnds=confint(F);
                        bounds=[bnds(1,1) bnds(2,1) bnds(1,2) bnds(2,2) bnds(1,3) bnds(2,3) log(2)/bnds(2,2) log(2)/bnds(1,2) 1/bnds(2,2) 1/bnds(1,2)];
                        statistics=[F1.dfe F1.rsquare F1.sse F1.rmse F2.numobs];
                        
                        prism_column_exposure(:,i)=strrep(["";"";round(coefficients,app.rounding)';"";round(bounds,app.rounding)';"";"";"";round(statistics,app.rounding)'],".",",");
                        clear bnds bounds statistics F F1 F2 coefficients
                        
                        [F,F1,F2]=fit([x(1,i);(1:app.dec-6)'],A_decay(1:end,i),'a*exp(-b*x)+c','Start',[cca_a cca_b cca_c_dec]);
                        %Creating output table
                        coefficients=[F.a F.b F.c log(2)/F.b 1/F.b F.a+F.c];
                        bnds=confint(F);
                        bounds=[bnds(1,1) bnds(2,1) bnds(1,2) bnds(2,2) bnds(1,3) bnds(2,3) log(2)/bnds(2,2) log(2)/bnds(1,2) 1/bnds(2,2) 1/bnds(1,2)];
                        statistics=[F1.dfe F1.rsquare F1.sse F1.rmse F2.numobs];
                        
                        prism_column_decay(:,i)=strrep(["";"";round(coefficients,app.rounding)';"";round(bounds,app.rounding)';"";"";"";round(statistics,app.rounding)'],".",",");
                        clear bnds bounds statistics F F1 F2 coefficients
                    end
                    prism_column_exposure(1,1)="Exposure";
                    prism_column_exposure(2,1)=strrep(string(app.files(k)),'.txt','');
                    prism_column_decay(1,1)="Decay";
                    prism_column_decay(2,1)=strrep(string(app.files(k)),'.txt','');
                    
                    T=splitvars(table(table_head,prism_column_exposure,cell([27 2]),prism_column_decay));
                    writetable(T,app.path+"/"+"prism.xlsx",'Sheet',k,'WriteVariableNames',false)
                    
                    clear T prism_column_decay prism_column_exposure
                end
                clear table_head X_analysis x app.files app.cycle app.decay together_result_ks table_head A_exposure A_decay
            end
            clear N k i time_count cca_a cca_b cca_c_exp cca_c_dec
            app.Count_Photo.Text ="Finished";
            drawnow
            Enable_All_Photo(app);
        end

        % Button pushed function: Select_Button_Light, 
        % Select_Files_Photo
        function Select_Pushed(app, event)
            [app.files,app.path]= uigetfile('MultiSelect','on','*.txt;*.tit');
            app.Select_Label_Light.Value = app.path;
            app.Select_Label_Photo.Value = app.path;
        end

        % Value changed function: Box_Spec_KS
        function Box_Spec_KS_Value_Changed(app, event)
            if app.Box_Spec_KS.Value == false
                app.Spec_Number.Enable = 'off';
                app.Box_Save_Together.Enable = 'off';
            else
                app.Spec_Number.Enable = 'on';
                app.Box_Save_Together.Enable = 'on';
            end
        end

        % Value changed function: Box_Spec_Range
        function Box_Spec_Range_Value_Changed(app, event)
            if app.Box_Spec_Range.Value == false
                app.Spec_Range_Top.Enable = 'off';
                app.Spec_Range_Bot.Enable = 'off';
                app.Just.Enable = 'off';
            else
                app.Spec_Range_Top.Enable = 'on';
                app.Spec_Range_Bot.Enable = 'on';
                app.Just.Enable = 'on';
            end
            
        end

        % Button pushed function: OnlyanalysisButton
        function Only_Analysis_Pushed(app, event)
            Disable_All_Photo(app);
            app.Select_Files_Photo.Enable='off';
            app.Select_Label_Photo.Enable='off';
            Initialize(app)
            [app.files,app.path]= uigetfile('MultiSelect','on','*.txt');
            app.Count_Photo.Text ="Analysing";
            drawnow
            X_imp=readtable(app.path+"/"+app.files);
            v=X_imp.Properties.VariableNames;
            X=table2array(X_imp(2:end,2:end));
            
            table_head={"One phase exponential fitting";"Best-fit values";"Span";"K";"Plateau";"Half Life";"Tau";"Y0";...
                "95% confidence bounds";"Span";"";"K";"";"Plateau";"";"Half life";"";"Tau";"";"Y0";"";...
                "Goodness of Fit";"Degrees of freedom";"R squared";"Sum of Squares";"Root mean squared";"Number of Y values"};
            
            prism_column_exposure=string(zeros([27 app.cycle]));
            prism_column_decay=string(zeros([27 app.cycle]));
            
            
            x=1:app.cycle;
            
            A_exposure=zeros([app.exp-5 app.cycle]);
            A_decay=zeros([app.dec-5 app.cycle]);
            warning('off','MATLAB:xlswrite:AddSheet');


            [~,N]=size(X);
            for k=1:N
                
                
                [row,~]=find(X(1:(app.dec+20),k)>1);
                idx=row(1)+10;
                
                
                for i=1:app.cycle
                    while 0.1<X(idx,k)-X(idx-1,k) || X(idx,k)>1
                        idx=idx-1;
                    end
                    exposure_mean=mean(X(idx-400:idx-100,k));
                    A_exposure(:,i)=X(idx:idx+app.exp-6,k);
                    
                    x(i)=((exposure_mean-A_exposure(2,i))/(A_exposure(3,i)-A_exposure(2,i)))+1;
                    
                    idx=idx+app.exp+5;
                    
                    while 0.01<X(idx-1,k)-X(idx,k) || X(idx,k)<X(idx-2,k)
                        idx=idx-1;
                    end
                    A_decay(:,i)=X(idx:idx+app.dec-6,k);
                    idx=idx+app.dec+5;
                    
                end
                
                clear idx exposure_mean row
                
                %Exposure
                %Finding coefficients
                cca_a=together_result_ks(app.dec+app.exp/2,k);
                cca_b=0.1;
                cca_c_exp=together_result_ks(app.dec+app.exp/2,k);
                cca_c_dec=together_result_ks(app.dec/2,k);
                for i=1:app.cycle
                    [F,F1,F2]=fit([x(1,i);(1:app.exp-6)'],A_exposure(1:end,i),'a*exp(-b*x)+c','Start',[-cca_a cca_b cca_c_exp]);
                    %Creating output table
                    coefficients=[F.a F.b F.c log(2)/F.b 1/F.b F.a+F.c];
                    bnds=confint(F);
                    bounds=[bnds(1,1) bnds(2,1) bnds(1,2) bnds(2,2) bnds(1,3) bnds(2,3) log(2)/bnds(2,2) log(2)/bnds(1,2) 1/bnds(2,2) 1/bnds(1,2)];
                    statistics=[F1.dfe F1.rsquare F1.sse F1.rmse F2.numobs];
                    
                    prism_column_exposure(:,i)=strrep(["";"";round(coefficients,app.rounding)';"";round(bounds,app.rounding)';"";"";"";round(statistics,app.rounding)'],".",",");
                    clear bnds bounds statistics F F1 F2 coefficients
                    
                    [F,F1,F2]=fit([x(1,i);(1:app.dec-6)'],A_decay(1:end,i),'a*exp(-b*x)+c','Start',[cca_a cca_b cca_c_dec]);
                    %Creating output table
                    coefficients=[F.a F.b F.c log(2)/F.b 1/F.b F.a+F.c];
                    bnds=confint(F);
                    bounds=[bnds(1,1) bnds(2,1) bnds(1,2) bnds(2,2) bnds(1,3) bnds(2,3) log(2)/bnds(2,2) log(2)/bnds(1,2) 1/bnds(2,2) 1/bnds(1,2)];
                    statistics=[F1.dfe F1.rsquare F1.sse F1.rmse F2.numobs];
                    
                    prism_column_decay(:,i)=strrep(["";"";round(coefficients,app.rounding)';"";round(bounds,app.rounding)';"";"";"";round(statistics,app.rounding)'],".",",");
                    clear bnds bounds statistics F F1 F2 coefficients
                end
                prism_column_exposure(1,1)="Exposure";
                prism_column_exposure(2,1)=v(1,k+1);
                prism_column_decay(1,1)="Decay";
                prism_column_decay(2,1)=v(1,k+1);
                
                T=splitvars(table(table_head,prism_column_exposure,cell([27 2]),prism_column_decay));
                writetable(T,app.path+"/"+"prism.xlsx",'Sheet',k,'WriteVariableNames',false)
                
                clear T prism_column_decay prism_column_exposure


            end


            clear files path N X A_decay A_exposure i k table_head v X_imp x cca_a cca_b cca_c_exp cca_c_dec
            app.Select_Files_Photo.Enable='on';
            app.Select_Label_Photo.Enable='on';
            app.Count_Photo.Text ="Finished";
            drawnow
            Enable_All_Photo(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LightningTab = uitab(app.TabGroup);
            app.LightningTab.Title = 'Lightning';
            app.L_Device = uilamp(app.LightningTab);
            app.L_Device.Visible = 'off';
            app.L_Files_Light = uilamp(app.LightningTab);
            app.L_Files_Light.Visible = 'off';
            app.Execute_Light = uibutton(app.LightningTab, 'push');
            app.Execute_Light.ButtonPushedFcn = createCallbackFcn(app, @Execute_Light_Pushed, true);
            app.Execute_Light.Text = 'Execute';
            app.Count_Light = uilabel(app.LightningTab);
            app.Count_Light.Text = '';
            app.Ex_Label_Light = uilabel(app.LightningTab);
            app.Ex_Label_Light.Text = 'Execute processing of the files';
            app.SelectmeasurementdeviceLabel = uilabel(app.LightningTab);
            app.SelectmeasurementdeviceLabel.Text = 'Select measurement device';
            app.Select_Device = uidropdown(app.LightningTab);
            app.Select_Device.Items = {'Select', 'STS', 'USB 2000', 'Avantes 8 Dual', 'Avantes 6'};
            app.Select_Device.Value = 'Select';
            app.Select_Button_Light = uibutton(app.LightningTab, 'push');
            app.Select_Button_Light.ButtonPushedFcn = createCallbackFcn(app, @Select_Pushed, true);
            app.Select_Button_Light.Text = 'Select';
            app.SelectingfilesforprocessingEditFieldLabel = uilabel(app.LightningTab);
            app.SelectingfilesforprocessingEditFieldLabel.Text = 'Selecting files for processing';
            app.Select_Label_Light = uieditfield(app.LightningTab, 'text');
            app.Select_Label_Light.Editable = 'off';
            app.Select_Label_Light.Value = ' ';
            app.Lam = uilamp(app.LightningTab);
            app.PhotochromTab = uitab(app.TabGroup);
            app.PhotochromTab.Title = 'Photochrom';
            app.Execute_Photo = uibutton(app.PhotochromTab, 'push');
            app.Execute_Photo.ButtonPushedFcn = createCallbackFcn(app, @Execute_Photo_Pushed, true);
            app.Execute_Photo.Text = 'Execute';
            app.Count_Photo = uilabel(app.PhotochromTab);
            app.Count_Photo.Text = '';
            app.Ex_Label_Photo = uilabel(app.PhotochromTab);
            app.Ex_Label_Photo.Text = 'Execute processing of the files';
            app.Select_Files_Photo = uibutton(app.PhotochromTab, 'push');
            app.Select_Files_Photo.ButtonPushedFcn = createCallbackFcn(app, @Select_Pushed, true);
            app.Select_Files_Photo.Text = 'Select';
            app.SelectingfilesforprocessingEditFieldLabel_2 = uilabel(app.PhotochromTab);
            app.SelectingfilesforprocessingEditFieldLabel_2.Text = 'Selecting files for processing';
            app.Select_Label_Photo = uieditfield(app.PhotochromTab, 'text');
            app.Select_Label_Photo.Editable = 'off';
            app.Select_Label_Photo.Value = ' ';
            app.ReflectanceLabel = uilabel(app.PhotochromTab);
            app.ReflectanceLabel.Text = 'Reflectance';
            app.Box_1nm = uicheckbox(app.PhotochromTab);
            app.Box_1nm.Text = '1 nm timetable';
            app.Box_5nm = uicheckbox(app.PhotochromTab);
            app.Box_5nm.Text = '5 nm timetable';
            app.Box_5nm.Value = true;
            app.Save_Label = uilabel(app.PhotochromTab);
            app.Save_Label.Text = 'What to save';
            app.Spec_Number = uieditfield(app.PhotochromTab, 'numeric');
            app.Spec_Number.Limits = [380 780];
            app.Spec_Number.RoundFractionalValues = 'on';
            app.Spec_Number.ValueDisplayFormat = '%.0f';
            app.Spec_Number.Value = 570;
            app.KSLabel = uilabel(app.PhotochromTab);
            app.KSLabel.Text = 'K/S';
            app.Box_Spec_KS = uicheckbox(app.PhotochromTab);
            app.Box_Spec_KS.ValueChangedFcn = createCallbackFcn(app, @Box_Spec_KS_Value_Changed, true);
            app.Box_Spec_KS.Text = 'Values fo specified wavelength';
            app.Box_Spec_KS.Value = true;
            app.Box_Spec_Range = uicheckbox(app.PhotochromTab);
            app.Box_Spec_Range.ValueChangedFcn = createCallbackFcn(app, @Box_Spec_Range_Value_Changed, true);
            app.Box_Spec_Range.Text = 'Values for specified range of wavelengths';
            app.Box_KS_1nm = uicheckbox(app.PhotochromTab);
            app.Box_KS_1nm.Text = '1 nm timetable';
            app.Box_KS_5nm = uicheckbox(app.PhotochromTab);
            app.Box_KS_5nm.Text = '5 nm timetable';
            app.Box_KS_5nm.Value = true;
            app.Spec_Range_Top = uieditfield(app.PhotochromTab, 'numeric');
            app.Spec_Range_Top.Limits = [380 780];
            app.Spec_Range_Top.RoundFractionalValues = 'on';
            app.Spec_Range_Top.ValueDisplayFormat = '%.0f';
            app.Spec_Range_Top.Enable = 'off';
            app.Spec_Range_Top.Value = 680;
            app.Just = uilabel(app.PhotochromTab);
            app.Just.Enable = 'off';
            app.Just.Text = ':';
            app.Spec_Range_Bot = uieditfield(app.PhotochromTab, 'numeric');
            app.Spec_Range_Bot.Limits = [380 780];
            app.Spec_Range_Bot.RoundFractionalValues = 'on';
            app.Spec_Range_Bot.ValueDisplayFormat = '%.0f';
            app.Spec_Range_Bot.Enable = 'off';
            app.Spec_Range_Bot.Value = 420;
            app.Box_Save_Together = uicheckbox(app.PhotochromTab);
            app.Box_Save_Together.Text = 'Save together';
            app.Box_Save_Together.Value = true;
            app.Time_Range_Bot = uieditfield(app.PhotochromTab, 'numeric');
            app.Time_Range_Bot.LowerLimitInclusive = 'off';
            app.Time_Range_Bot.UpperLimitInclusive = 'off';
            app.Time_Range_Bot.Limits = [1 Inf];
            app.Time_Range_Bot.RoundFractionalValues = 'on';
            app.Time_Range_Bot.ValueDisplayFormat = '%.0f';
            app.Time_Range_Bot.Value = 900;
            app.Just_2 = uilabel(app.PhotochromTab);
            app.Just_2.Text = ':';
            app.Time_Range_Top = uieditfield(app.PhotochromTab, 'numeric');
            app.Time_Range_Top.LowerLimitInclusive = 'off';
            app.Time_Range_Top.UpperLimitInclusive = 'off';
            app.Time_Range_Top.Limits = [1 Inf];
            app.Time_Range_Top.RoundFractionalValues = 'on';
            app.Time_Range_Top.ValueDisplayFormat = '%.0f';
            app.Time_Range_Top.Value = 150;
            app.Cycle_Count = uieditfield(app.PhotochromTab, 'numeric');
            app.Cycle_Count.Limits = [1 Inf];
            app.Cycle_Count.Value = 5;
            app.CyclecountLabel = uilabel(app.PhotochromTab);
            app.CyclecountLabel.Text = 'Cycle count';
            app.UVonoffLabel = uilabel(app.PhotochromTab);
            app.UVonoffLabel.Text = 'UV on/off';
            app.DeviceDropDownLabel = uilabel(app.PhotochromTab);
            app.DeviceDropDownLabel.Text = 'Device';
            app.Device_Photo = uidropdown(app.PhotochromTab);
            app.Device_Photo.Items = {'Photochrome 2', 'Photochrome 3'};
            app.Device_Photo.Value = 'Photochrome 2';
            app.Force_Stop = uibutton(app.PhotochromTab, 'state');
            app.Force_Stop.Visible = 'off';
            app.Force_Stop.Text = 'Force Stop';
            app.Force_Stop.Value = true;
            app.Time_Count_Photo = uilabel(app.PhotochromTab);
            app.Time_Count_Photo.Text = '';
            app.Box_Analysis = uicheckbox(app.PhotochromTab);
            app.Box_Analysis.Text = 'Analysis';
            app.Box_Analysis.Value = true;
            app.OnlyanalysisButton = uibutton(app.PhotochromTab, 'push');
            app.OnlyanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @Only_Analysis_Pushed, true);
            app.OnlyanalysisButton.Text = 'Only analysis';
        end
    end

    methods (Access = public)

        % Construct app
        function app = light_app_v_6

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end