classdef light_app_v_9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LightningTab                  matlab.ui.container.Tab
        L_Device                      matlab.ui.control.Lamp
        L_Files_Light                 matlab.ui.control.Lamp
        Execute_Light                 matlab.ui.control.Button
        Count_Light                   matlab.ui.control.Label
        Ex_Label_Light                matlab.ui.control.Label
        SelectmeasurementdeviceLabel  matlab.ui.control.Label
        Select_Device                 matlab.ui.control.DropDown
        Select_Button_Light           matlab.ui.control.Button
        SelectingfilesforprocessingEditFieldLabel  matlab.ui.control.Label
        Select_Label_Light            matlab.ui.control.EditField
        Lam                           matlab.ui.control.Lamp
        PhotochromTab                 matlab.ui.container.Tab
        Execute_Photo                 matlab.ui.control.Button
        Count_Photo                   matlab.ui.control.Label
        Ex_Label_Photo                matlab.ui.control.Label
        Select_Files_Photo            matlab.ui.control.Button
        SelectingfilesforprocessingEditFieldLabel_2  matlab.ui.control.Label
        Select_Label_Photo            matlab.ui.control.EditField
        ReflectanceLabel              matlab.ui.control.Label
        Box_1nm                       matlab.ui.control.CheckBox
        Box_5nm                       matlab.ui.control.CheckBox
        Save_Label                    matlab.ui.control.Label
        Spec_Number                   matlab.ui.control.NumericEditField
        KSLabel                       matlab.ui.control.Label
        Box_Spec_KS                   matlab.ui.control.CheckBox
        Box_Spec_Range                matlab.ui.control.CheckBox
        Box_KS_1nm                    matlab.ui.control.CheckBox
        Box_KS_5nm                    matlab.ui.control.CheckBox
        Spec_Range_Top                matlab.ui.control.NumericEditField
        Just                          matlab.ui.control.Label
        Spec_Range_Bot                matlab.ui.control.NumericEditField
        Box_Save_Together             matlab.ui.control.CheckBox
        Time_Range_Bot                matlab.ui.control.NumericEditField
        Just_2                        matlab.ui.control.Label
        Time_Range_Top                matlab.ui.control.NumericEditField
        Cycle_Count                   matlab.ui.control.NumericEditField
        CyclecountLabel               matlab.ui.control.Label
        UVonoffLabel                  matlab.ui.control.Label
        DeviceDropDownLabel           matlab.ui.control.Label
        Device_Photo                  matlab.ui.control.DropDown
        Force_Stop                    matlab.ui.control.StateButton
        Time_Count_Photo              matlab.ui.control.Label
        Box_Analysis                  matlab.ui.control.CheckBox
        OnlyanalysisButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        files
        path = ' ';
        M %Length of index array
        nano %Nnaometer list
        index %This is an array of numbers whch will be selected as "core" for computing chosen nanometer
        range %Length of index array for lightning
        pass %Propably not useful just for checking
        rounding %Results that are outputted will be rounded to 4
        cycle %Number of cycles in easurement
        nm %Specific nanometer for saving an
        nm_range %Nanometer range for saving
        exp %Exposure time
        dec %Decay time
        slash %Used for making filepath win=\, mac=/
    end
    
    methods (Access = private)
        
        function Check_Ocean_View(app)
            app.L_Files_Light.Visible = 'off';
            app.L_Device.Visible='off';
            if app.path == ' '
                app.L_Files_Light.Visible = 'on';
                app.pass=0;
            elseif string(app.Select_Device.Value) == "Select"
                app.L_Device.Visible='on';
                app.pass=0;
            else
                app.pass=1;
            end
            
        end
        
        function Ocean_View_Optics(app)
            
            %If only one file is chosen the result is character string sadly
            if 1==ischar(app.files)
                N=1;
            else
                N = length(app.files);
            end
            app.Count_Light.Text = "Importing";
            drawnow
            %Creating table for storing and outputing the results I hope there won't be
            %need for more than 1024 columns
            result_average = zeros([(N) app.M]);
            result_deviation = zeros([(N) app.M]);
            average_1nm=zeros([N length(app.nano)]);
            deviation_1nm=zeros([N length(app.nano)]);
            average_5nm=zeros([N+1 (((length(app.nano)-1)/5)+1)]);
            deviation_5nm=zeros([N+1 (((length(app.nano)-1)/5)+1)]);
            
            %Producing opts because it saves 30 seconds per operation  with same results
            if N==1
                file_path=app.path+app.slash+app.files;
            else
                file_path=app.path+app.slash+app.files(1,1);
            end
            opts=detectImportOptions(file_path);
            opts.VariableTypes(1,:)={'char'};
            clear file_path
            
            %Core processing loop
            for i = 1:N
                app.Count_Light.Text = i+app.slash+N;
                drawnow
                if N==1
                    file_path=app.path+app.slash+app.files;
                else
                    file_path=app.path+app.slash+app.files(1,i);
                end
                
                %This makes the table. The cells are being read as a character string,
                %which are then transformed into numbers while removing unnecessary cells
                X = readtable(file_path,opts);
                X(:,2)=[];
                X(:,1)=[];
                X{:,:}=(regexprep(X{:,:},',','.'));
                A=str2double(table2cell(X));
                A(1,:)=[];
                
                %Just computing the average and standart deviation using 1/N-1 formula
                result_average(i,:)=mean(A);
                result_deviation(i,:)=std(A);
                
                clear X file_path A m n average standart_deviation
            end
            
            %Transforming from decimal nanometers to integer nanometers with step 1 nanometer
            for k=1:N
                for j=1:length(app.nano)
                    idx=app.index(1,j);
                    average_1nm(k,j)=(result_average(k,idx-2) - 12*result_average(k,idx-1) + ...
                        120*result_average(k,idx)-12*result_average(k,idx+1) + result_average(k,idx+2))/98;
                    deviation_1nm(k,j)=(result_deviation(k,idx-2) - 12*result_deviation(k,idx-1) +...
                        120*result_deviation(k,idx)-12*result_deviation(k,idx+1) + result_deviation(k,idx+2))/98;
                    
                end
            end
            average_1nm=[app.nano;average_1nm];
            deviation_1nm=[app.nano;deviation_1nm];
            
            %Selecting every 5th nanometer to create smaller table
            average_5nm(:,1)=average_1nm(:,1);
            deviation_5nm(:,1)=deviation_1nm(:,1);
            for i=1:((length(app.nano)-1)/5)
                average_5nm(:,i+1)=average_1nm(:,i*5+1);
                deviation_5nm(:,i+1)=deviation_1nm(:,i*5+1);
            end
            
            
            %Finally outputting averaged raw data from the device
            names = ["" app.files];
            
            T_average=table(names',round(average_1nm,app.rounding));
            writetable(T_average,app.path+app.slash+"average values 1nm.txt",'Delimiter','\t');
            
            T_deviation=table(names',round(deviation_1nm,app.rounding));
            writetable(T_deviation,app.path+app.slash+"standart deviation 1nm.txt",'Delimiter','\t');
            
            T_average=table(names',round(average_5nm,app.rounding));
            writetable(T_average,app.path+app.slash+"average values 5nm.txt",'Delimiter','\t');
            
            T_deviation=table(names',round(deviation_5nm,app.rounding));
            writetable(T_deviation,app.path+app.slash+"standart deviation 5nm.txt",'Delimiter','\t');
            
            clear average_1nm average_5nm deviation_1nm deviation_5nm i opts names N nano path result_average
            clear result_deviation T_average T_deviation files j k index idx deviation average
            
        end
        
        function Check_Photochrome(app)
            if app.path==' '
                app.pass=0;
            else
                app.pass=1;
            end
        end
        
        function Initialize(app)
            if ispc
                app.slash = "\";
            else
                app.slash = "/";
            end
            app.rounding = 4;
            app.cycle = app.Cycle_Count.Value;
            app.nm = app.Spec_Number.Value;
            app.nm_range = [app.Spec_Range_Bot.Value app.Spec_Range_Top.Value];
            app.exp = app.Time_Range_Top.Value;
            app.dec = app.Time_Range_Bot.Value;
        end
        
        function Enable_All_Photo(app)
            if app.Box_Spec_Range.Value == true
                app.Spec_Range_Top.Enable='on';
                app.Spec_Range_Bot.Enable='on';
                app.Just.Enable='on';
            else
                app.Spec_Range_Top.Enable='off';
                app.Spec_Range_Bot.Enable='off';
                app.Just.Enable='off';
            end
            
            if app.Box_Spec_KS.Value == true
                app.Spec_Number.Enable='on';
                app.Box_Save_Together.Enable='on';
            else
                app.Spec_Number.Enable='off';
                app.Box_Save_Together.Enable='off';
            end
            
            app.Execute_Photo.Enable='on';
            app.Time_Range_Bot.Enable='on';
            app.Time_Range_Top.Enable='on';
            app.Just_2.Enable='on';
            app.Cycle_Count.Enable='on';
            app.Device_Photo.Enable='on';
            app.Box_1nm.Enable='on';
            app.Box_5nm.Enable='on';
            app.Box_Spec_KS.Enable='on';
            app.Box_Spec_Range.Enable='on';
            app.Box_KS_1nm.Enable='on';
            app.Box_KS_5nm.Enable='on';
            app.Force_Stop.Visible='off';
            app.Box_Analysis.Enable='on';
            app.OnlyanalysisButton.Enable = 'on';
        end
        
        function Disable_All_Photo(app)
            app.Box_Analysis.Enable='off';
            app.Execute_Photo.Enable='off';
            app.Spec_Range_Top.Enable='off';
            app.Spec_Range_Bot.Enable='off';
            app.Just.Enable='off';
            app.Box_Save_Together.Enable='off';
            app.Time_Range_Bot.Enable='off';
            app.Time_Range_Top.Enable='off';
            app.Just_2.Enable='off';
            app.Cycle_Count.Enable='off';
            app.Device_Photo.Enable='off';
            app.Box_1nm.Enable='off';
            app.Box_5nm.Enable='off';
            app.Box_Spec_KS.Enable='off';
            app.Box_Spec_Range.Enable='off';
            app.Box_KS_1nm.Enable='off';
            app.Box_KS_5nm.Enable='off';
            app.Spec_Number.Enable='off';
            app.Force_Stop.Value=false;
            app.OnlyanalysisButton.Enable = 'off';
        end
        
        function [fit_exp,fit_dec,x] = Curve_Fitting(app,A,B,lvm)
            
            fit_exp=string(zeros([27 app.cycle]));
            fit_dec=string(zeros([27 app.cycle]));
            
            if lvm; step=2; else; step=1;end
            
            %Declaring fitting coefficients
            cca_a=A(app.exp-10);
            cca_b=0.1;
            cca_c_dec=B(app.dec-10);
            x=1:app.cycle;
            x(1,:)=((mean(B((end-ceil(app.dec/4)):end-10,:))-A(2,:))./(A(3,:)-A(2,:)))+1;
            %Fitting Loop
            for i=1:app.cycle
                [F,F1,F2]=fit([x(1,i);(step:step:(app.exp-6)*step)'],A(1:end,i),'a*exp(-b*x)+c','Start',[-cca_a cca_b cca_a]);
                
                %Creating output table
                coefficients=[F.a F.b F.c log(2)/F.b 1/F.b F.a+F.c];
                bnds=confint(F);
                bounds=[bnds(1,1) bnds(2,1) bnds(1,2) bnds(2,2) bnds(1,3) bnds(2,3) log(2)/bnds(2,2) log(2)/bnds(1,2) 1/bnds(2,2) 1/bnds(1,2)];
                statistics=[F1.dfe F1.rsquare F1.sse F1.rmse F2.numobs];
                
                fit_exp(:,i)=strrep(["";"";round(coefficients,app.rounding)';"";round(bounds,app.rounding)';"";x(i);"";round(statistics,app.rounding)'],".",",");
                clear bnds bounds statistics F F1 F2 coefficients
                
                if i+lvm>app.cycle
                    coefficients=[0 0 0 0 0 0];
                    bounds=[0 0 0 0 0 0 0 0 0 0];
                    statistics=[0 0 0 0 0];
                else
                    
                    [F,F1,F2]=fit((0:step:(app.dec-6)*step)',B(1:end,i),'a*exp(-b*x)+c','Start',[cca_a cca_b cca_c_dec]);
                    
                    %Creating output table
                    coefficients=[F.a F.b F.c log(2)/F.b 1/F.b F.a+F.c];
                    bnds=confint(F);
                    bounds=[bnds(1,1) bnds(2,1) bnds(1,2) bnds(2,2) bnds(1,3) bnds(2,3) log(2)/bnds(2,2) log(2)/bnds(1,2) 1/bnds(2,2) 1/bnds(1,2)];
                    statistics=[F1.dfe F1.rsquare F1.sse F1.rmse F2.numobs];
                end
                
                fit_dec(:,i)=strrep(["";"";round(coefficients,app.rounding)';"";round(bounds,app.rounding)';"";"";"";round(statistics,app.rounding)'],".",",");
                clear bnds bounds statistics F F1 F2 coefficients
            end
            clear cca_a cca_b cca_c_exp cca_c_dec
            fit_exp(1,1)="Exposure";
            fit_dec(1,1)="Decay";
        end
        
        function plotRefl(app)
            
        end
        
        function createRefl(app)
            fig1 = uifigure('Visible', 'off');
            fig1.Position = [100 100 1053 627];
            fig1.Name = 'Reflectance Plots';
            
            Reflectance_decay = uiaxes(fig1);
            title(Reflectance_decay, 'Reflectance in decay phase')
            xlabel(Reflectance_decay, 'Wavelength [nm]')
            ylabel(Reflectance_decay, 'Reflectance [%]')
            Reflectance_decay.Position = [28 309 500 300];
            
            Reflectance_exposure = uiaxes(fig1);
            title(Reflectance_exposure, 'Reflectance in exposure phase')
            xlabel(Reflectance_exposure, 'Wavelength [nm]')
            ylabel(Reflectance_exposure, 'Reflectance [%]')
            Reflectance_exposure.Position = [527 309 500 300];
            
            Reflectance_time = uiaxes(fig1);
            title(Reflectance_time, 'Reflectance in time')
            xlabel(Reflectance_time, 'Time [s]')
            ylabel(Reflectance_time, 'Reflectance [%]')
            Reflectance_time.Position = [27 10 1000 300];
            
            fig1.Visible = 'on';
        end
        
        function file_is_lvm = islvm(~,file)
            file=char(file);
            file_is_lvm=string(file(end-2:end))=="lvm";
        end
        
        function data = lvmimport(app,file)
            G=fileread(file);
            G=G(:);
            N=[175500,0,0,0;263250,351000,0,0;438750,526500,702000,0;614250,0,877500,1053000];
            N=abs(N-length(G));
            [row,col]=find(N==min(min(N)));
            switch row
                case 1
                    app.dec=75;
                case 2
                    app.dec=150;
                case 3
                    app.dec=300;
                case 4
                    app.dec=450;
            end
            switch col
                case 1
                    app.exp=75;
                case 2
                    app.exp=150;
                case 3
                    app.exp=300;
                case 4
                    app.exp=450;
            end
            data=strrep(string(ones([(app.exp+app.dec)*5+5,39])),'1','');k=1;i=1;l=1;tb=char(9);en=char(13);
            while i<length(G)
                if G(i)==tb
                    l=l+1;
                elseif G(i)==en
                    k=k+1;
                    i=i+1;
                    l=1;
                else
                    data(k,l)=data(k,l)+G(i);
                end
                i=i+1;
            end
            data=str2double(strrep(data,',','.'));
            data(isnan(data(:,1)),:)=[];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Execute_Light
        function Execute_Light_Pushed(app, event)
            app.Count_Light.Text = "Checking";
            drawnow
            Check_Ocean_View(app)
            if app.pass == 0
                app.Lam.Visible='on';
                app.Count_Light.Text = "Checking failed";
                return
            end
            %Here starts the real show
            switch string(app.Select_Device.Value)
                case 'STS'
                    app.M = 1024;
                    app.nano=185:665;
                    app.index=[4,7,9,11,13,16,18,20,22,24,27,29,31,33,35,38,40,42,44,47,49,51,53,55,58,60,62,64,66,69,71,73,75,77,80,82,84,86,...
                        88,91,93,95,97,99,102,104,106,108,110,113,115,117,119,121,124,126,128,130,132,135,137,139,141,143,146,148,150,152,154,...
                        156,159,161,163,165,167,170,172,174,176,178,181,183,185,187,189,191,194,196,198,200,202,205,207,209,211,213,215,218,...
                        220,222,224,226,228,231,233,235,237,239,241,244,246,248,250,252,255,257,259,261,263,265,268,270,272,274,276,278,281,...
                        283,285,287,289,291,293,296,298,300,302,304,306,309,311,313,315,317,319,322,324,326,328,330,332,334,337,339,341,343,...
                        345,347,350,352,354,356,358,360,362,365,367,369,371,373,375,377,380,382,384,386,388,390,392,395,397,399,401,403,405,...
                        407,410,412,414,416,418,420,422,425,427,429,431,433,435,437,439,442,444,446,448,450,452,454,457,459,461,463,465,467,...
                        469,471,474,476,478,480,482,484,486,488,491,493,495,497,499,501,503,505,508,510,512,514,516,518,520,522,524,527,529,...
                        531,533,535,537,539,541,544,546,548,550,552,554,556,558,560,563,565,567,569,571,573,575,577,579,581,584,586,588,590,...
                        592,594,596,598,600,602,605,607,609,611,613,615,617,619,621,623,626,628,630,632,634,636,638,640,642,644,647,649,651,...
                        653,655,657,659,661,663,665,667,670,672,674,676,678,680,682,684,686,688,690,692,695,697,699,701,703,705,707,709,711,...
                        713,715,717,719,722,724,726,728,730,732,734,736,738,740,742,744,746,749,751,753,755,757,759,761,763,765,767,769,771,...
                        773,775,778,780,782,784,786,788,790,792,794,796,798,800,802,804,806,808,811,813,815,817,819,821,823,825,827,829,831,...
                        833,835,837,839,841,843,845,848,850,852,854,856,858,860,862,864,866,868,870,872,874,876,878,880,882,884,886,889,891,...
                        893,895,897,899,901,903,905,907,909,911,913,915,917,919,921,923,925,927,929,931,933,935,937,939,942,944,946,948,950,...
                        952,954,956,958,960,962,964,966,968,970,972,974,976,978,980,982,984,986,988,990,992,994,996,998,1000,1002,1004,1006,...
                        1008,1010,1012,1014,1017,1019,1021];
                    app.range= 481;
                    Ocean_View_Optics(app);
                case 'USB 2000'
                    app.M = 2048;
                    app.nano=185:870;
                    app.index=[14,17,20,22,25,28,30,33,36,38,41,44,47,49,52,55,57,60,63,66,68,71,74,76,79,82,85,87,90,93,95,98,101,104,106,109,...
                        112,114,117,120,123,125,128,131,133,136,139,142,144,147,150,153,155,158,161,163,166,169,172,174,177,180,183,185,188,191,...
                        194,196,199,202,204,207,210,213,215,218,221,224,226,229,232,235,237,240,243,246,248,251,254,257,259,262,265,268,270,273,...
                        276,279,281,284,287,290,292,295,298,301,303,306,309,312,314,317,320,323,325,328,331,334,336,339,342,345,347,350,353,356,...
                        358,361,364,367,370,372,375,378,381,383,386,389,392,395,397,400,403,406,408,411,414,417,419,422,425,428,431,433,436,439,...
                        442,445,447,450,453,456,458,461,464,467,470,472,475,478,481,484,486,489,492,495,498,500,503,506,509,512,514,517,520,523,...
                        526,528,531,534,537,540,542,545,548,551,554,556,559,562,565,568,571,573,576,579,582,585,587,590,593,596,599,602,604,607,...
                        610,613,616,618,621,624,627,630,633,635,638,641,644,647,650,652,655,658,661,664,667,669,672,675,678,681,684,686,689,692,...
                        695,698,701,704,706,709,712,715,718,721,723,726,729,732,735,738,741,743,746,749,752,755,758,761,764,766,769,772,775,778,...
                        781,784,786,789,792,795,798,801,804,807,809,812,815,818,821,824,827,830,832,835,838,841,844,847,850,853,856,858,861,864,...
                        867,870,873,876,879,882,884,887,890,893,896,899,902,905,908,911,913,916,919,922,925,928,931,934,937,940,943,945,948,951,...
                        954,957,960,963,966,969,972,975,978,981,983,986,989,992,995,998,1001,1004,1007,1010,1013,1016,1019,1022,1025,1027,1030,...
                        1033,1036,1039,1042,1045,1048,1051,1054,1057,1060,1063,1066,1069,1072,1075,1078,1081,1084,1086,1089,1092,1095,1098,1101,...
                        1104,1107,1110,1113,1116,1119,1122,1125,1128,1131,1134,1137,1140,1143,1146,1149,1152,1155,1158,1161,1164,1167,1170,1173,...
                        1176,1179,1182,1185,1188,1191,1194,1197,1200,1203,1206,1209,1212,1215,1218,1221,1224,1227,1230,1233,1236,1239,1242,1245,...
                        1248,1251,1254,1257,1260,1263,1266,1269,1272,1275,1278,1281,1284,1287,1290,1293,1296,1299,1302,1305,1308,1311,1314,1317,...
                        1321,1324,1327,1330,1333,1336,1339,1342,1345,1348,1351,1354,1357,1360,1363,1366,1369,1372,1375,1379,1382,1385,1388,1391,...
                        1394,1397,1400,1403,1406,1409,1412,1415,1418,1422,1425,1428,1431,1434,1437,1440,1443,1446,1449,1452,1456,1459,1462,1465,...
                        1468,1471,1474,1477,1480,1483,1487,1490,1493,1496,1499,1502,1505,1508,1511,1515,1518,1521,1524,1527,1530,1533,1536,1540,...
                        1543,1546,1549,1552,1555,1558,1562,1565,1568,1571,1574,1577,1580,1584,1587,1590,1593,1596,1599,1603,1606,1609,1612,1615,...
                        1618,1622,1625,1628,1631,1634,1637,1641,1644,1647,1650,1653,1656,1660,1663,1666,1669,1672,1676,1679,1682,1685,1688,1692,...
                        1695,1698,1701,1704,1708,1711,1714,1717,1720,1724,1727,1730,1733,1737,1740,1743,1746,1749,1753,1756,1759,1762,1766,1769,...
                        1772,1775,1779,1782,1785,1788,1792,1795,1798,1801,1805,1808,1811,1814,1818,1821,1824,1827,1831,1834,1837,1840,1844,1847,...
                        1850,1854,1857,1860,1863,1867,1870,1873,1877,1880,1883,1886,1890,1893,1896,1900,1903,1906,1910,1913,1916,1919,1923,1926,...
                        1929,1933,1936,1939,1943,1946,1949,1953,1956,1959,1963,1966,1969,1973,1976,1979,1983,1986,1990,1993,1996,2000,2003,2006,...
                        2010,2013,2016,2020,2023,2027,2030,2033,2037,2040,2043];
                    app.range= 686;
                    Ocean_View_Optics(app);
                case 'Avantes 8 Dual'
                    app.Lam.Color = [0 0 1];
                case 'Avantes 6'
                    app.Lam.Color = [1 0 1];
            end
            app.Count_Light.Text = "Finished";
        end

        % Button pushed function: Execute_Photo
        function Execute_Photo_Pushed(app, event)
            %Starting checking and processing
            Disable_All_Photo(app);
            app.Count_Photo.Text = "Checking";
            Initialize(app);
            Check_Photochrome(app);
            if app.pass == 0
                app.Count_Photo.Text = "Checking failed";
                Enable_All_Photo(app);
                return
            end
            app.Force_Stop.Visible='on';
            
            %If only one file is chosen the result is string
            if 1==ischar(app.files)
                N=1;
            else
                N = length(app.files);
            end
            
            %Index for searching and nanometer row
            if 1==1
                app.index=[3,6,9,12,14,17,20,23,25,28,31,34,37,39,42,45,48,50,53,56,59,62,64,67,70,73,76,...
                    78,81,84,87,89,92,95,98,101,103,106,109,112,115,117,120,123,126,129,132,134,137,140,143,...
                    146,148,151,154,157,160,163,165,168,171,174,177,179,182,185,188,191,194,196,199,202,205,...
                    208,211,213,216,219,222,225,228,231,233,236,239,242,245,248,251,253,256,259,262,265,268,...
                    271,273,276,279,282,285,288,291,293,296,299,302,305,308,311,314,316,319,322,325,328,331,...
                    334,337,340,342,345,348,351,354,357,360,363,366,369,371,374,377,380,383,386,389,392,395,...
                    398,401,403,406,409,412,415,418,421,424,427,430,433,436,439,441,444,447,450,453,456,459,...
                    462,465,468,471,474,477,480,483,486,489,491,494,497,500,503,506,509,512,515,518,521,524,...
                    527,530,533,536,539,542,545,548,551,554,557,560,563,566,569,572,575,578,581,584,587,590,...
                    593,596,599,602,605,608,611,614,617,620,623,626,629,632,635,638,641,644,647,650,653,656,...
                    659,662,665,668,671,674,677,680,683,686,689,692,695,698,701,704,707,711,714,717,720,723,...
                    726,729,732,735,738,741,744,747,750,753];
            end
            
            %Generating variable names
            drawnow
            if N==1
                v={'Measurement' strrep(strrep(strrep(app.files,' ',''),'.txt',''),'-','')};
            else
                v=['Measurement' strrep(strrep(strrep(app.files,' ',''),'.txt',''),'-','')];
            end
            
            
            %I though that we need to output time row as well, maybe implement later as a feature
            
            %time_output_low=dec/2;
            %time_output_high=dec+exp/2;
            
            if app.Device_Photo.Value == "Photochrome 2"
                table_length=(app.cycle * (app.exp + app.dec) + app.dec + 20);
            elseif app.Device_Photo.Value == "Photochrome 3"
                table_length=(app.cycle * (app.exp + app.dec) + 20);
            end
            
            %Result table for one wavelength
            together_result_ks=zeros([table_length N]);
            %together_result_w=zeros([table_length N]);
            
            
            %Core processing loop
            for i = 1:N
                if app.Force_Stop.Value==true
                    Enable_All_Photo(app);
                    break
                end
                tic
                app.Count_Photo.Text ="Importing file "+i+app.slash+N;
                drawnow
                if N==1
                    file_path=app.path+app.slash+app.files;
                    name=strrep(app.files,'.lvm','.txt');
                else
                    file_path=app.path+app.slash+app.files(1,i);
                    name=strrep(app.files(1,i),'.lvm','.txt');
                end
                
                %{
                This makes the table. The cells are being read as a character string,
                which are then transformed into numbers while removing unnecessary cells
                Also this is way faster than readtable function
                %}
                if islvm(app,file_path)
                    A=lvmimport(app,file_path);
                    [p,~]=size(A);
                    columns=39; step=2;
                    app.nano=380:10:760;


                else
                    A=readmatrix(file_path,'Delimiter',{'\t'},'DecimalSeparator',',');
                    A=A(2:end,3:end);
                    columns=401; step=1; app.nano=380:780;
                    [p,~]=size(A);
                    time=[[1650;2550;4350;6150],[2400;3300;5100;6900],[3900;4800;6600;8400],[5400;6300;8100;9900]];
                    k=time-p;
                    k(k<0)=k(k<0).*(-1);
                    [row,col]=find(k==min(min(k)));
                    switch row
                        case 1
                            app.dec=150;
                        case 2
                            app.dec=300;
                        case 3
                            app.dec=600;
                        case 4
                            app.dec=900;
                    end
                    switch col
                        case 1
                            app.exp=150;
                        case 2
                            app.exp=300;
                        case 3
                            app.exp=600;
                        case 4
                            app.exp=900;
                    end
                    clear k
                end
                together_result_ks=zeros([p N]);
                time_count=toc;
                app.Time_Count_Photo.Text = sprintf('%s\n%s',"Importing table "+i+app.slash+N+" took ", round(time_count,2)+" seconds");
                app.Count_Photo.Text ="Computing table "+i+app.slash+N;
                drawnow
                
                if islvm(app,file_path)
                    result=A;
                else
                    %Creating result of interpolation
                    result=zeros([p columns]);
                    
                    %Interpolation
                    for k=1:p
                        for l=1:259
                            idx=app.index(1,l);
                            result(k,l+41)=(A(k,idx-2) - 12*A(k,idx-1) + 120*A(k,idx)...
                                -12*A(k,idx+1) + A(k,idx+2))/98;
                        end
                    end
                    clear A
                    %Expanding to 380 from 421
                    for j=1:41
                        result(:,j)=result(:,42);
                    end
                    %expanding from 679 to 780
                    for j=301:401
                        result(:,j)=result(:,300);
                    end
                end
                
                %Computing K/S values
                ks_1nm=result;
                if islvm(app,file_path); div=1; else; div=100; end
                for k=1:p
                    for j=1:columns
                        B=ks_1nm(k,j)/div;
                        ks_1nm(k,j)=(((1-B)^2)/(2*B));
                    end
                end
                if islvm(app,file_path)
                    c=mean(ks_1nm(10:40,2+round(app.nm-380,-1)/10))*10<ks_1nm(:,2+round(app.nm-380,-1)/10);
                    c=abs(find(c,1)*2-[150,300,0,600,0,900]);
                    app.dec=find(c==min(c))*75;
                    app.exp=p/5-app.dec;
                end
                
                %Selecting every 5th nanometer
                result_1nm=([app.nano;result]);
                ks_1nm=([app.nano;ks_1nm]);
                if ~islvm(app,file_path)
                    result_5nm=result_1nm(:,(1:5:end));
                    ks_5nm=ks_1nm(:,(1:5:end));
                end
                
                %Creating timetables for export
                time=[0;(0:step:p*step-1)'];
                result_1nm=([time, result_1nm]);
                ks_1nm=([time, ks_1nm]);
                if ~islvm(app,file_path)
                    result_5nm=([time, result_5nm]);
                    ks_5nm=([time, ks_5nm]);
                end
                
                %{
                subplot(2,2,1);
                title('Reflectance in minimum');
                plot(result_1nm(app.dec/2,2:end))
                subplot(2,2,2);
                title('Reflectance in maximum');
                plot(result_1nm(app.dec+app.exp/2,2:end))
                subplot(2,2,[3,4]);
                title("Wavelength in "+app.nm+"th nanometer");
                plot(ks_1nm(2:end,app.nm-379))
                drawnow
                %}
                
                %Saving
                if 1==1
                    if app.Box_1nm.Value
                        T_result_1nm=table(round(result_1nm,app.rounding));
                        writetable(T_result_1nm,app.path+"/1 nm result "+name,'Delimiter','\t');
                        clear T_result_1nm
                    end
                    
                    if app.Box_5nm.Value && ~islvm(app,file_path)
                        T_result_5nm=table(round(result_5nm,app.rounding));
                        writetable(T_result_5nm,app.path+"/5 nm result "+name,'Delimiter','\t');
                        clear T_result_5nm
                    end
                    
                    if app.Box_KS_1nm.Value
                        T_ks_1nm=table(round(ks_1nm,app.rounding));
                        writetable(T_ks_1nm,app.path+"/KS 1nm "+name,'Delimiter','\t');
                        clear T_ks_1nm
                    end
                    
                    if app.Box_KS_5nm.Value && ~islvm(app,file_path)
                        T_ks_5nm=table(round(ks_5nm,app.rounding));
                        writetable(T_ks_5nm,app.path+"/KS 5nm "+name,'Delimiter','\t');
                        clear T_ks_5nm
                    end
                    %{
                if app.==true
                    T_nanometer=table(time,round(result_1nm(:,app.nm-378),app.rounding));
                    writetable(T_nanometer,app.path+app.slash+app.nm+" nanometer Reflectance "+name,'Delimiter','\t');
                    clear T_nanometer
                end
                    %}
                    if (app.Box_Spec_KS.Value) && (app.Box_Save_Together.Value == false)
                        if islvm(app,file_path)
                            if app.nm>760; app.nm=760;end %Fix nanometer when lvm because of rounding TODO same with range
                            T_KS_nanometer=table(time,round(ks_1nm(:,2+round(app.nm-380,-1)/10),app.rounding));
                        else
                            T_KS_nanometer=table(time,round(ks_1nm(:,app.nm-378),app.rounding));
                        end
                        writetable(T_KS_nanometer,app.path+app.slash+app.nm+" nanometer KS "+name,'Delimiter','\t');
                        clear T_KS_nanometer
                    end
                    %{
                if app.==true
                    T_nanometer_range=table(time,round(result_1nm(:,app.nm_range(1,1)-378:app.nm_range(1,2)-378),app.rounding));
                    writetable(T_nanometer_range,app.path+app.slash+app.nm_range(1,1)+" : "+app.nm_range(1,2)+" nanometer Reflectance "+name,'Delimiter','\t');
                    clear T_nanometer_range
                end
                    %}
                    if app.Box_Spec_Range.Value
                        if islvm(app,file_path)
                            if app.nm_range(1,2)>760; app.nm_range(1,2)=760;end
                            T_KS_nanometer_range=table(time,round(ks_1nm(:,2+round(app.nm_range(1,1)-380,-1)/10:2+round(app.nm_range(1,2)-380,-1)/10),app.rounding));
                        else
                            T_KS_nanometer_range=table(time,round(ks_1nm(:,app.nm_range(1,1)-378:app.nm_range(1,2)-378),app.rounding));
                        end
                        writetable(T_KS_nanometer_range,app.path+app.slash+app.nm_range(1,1)+" : "+app.nm_range(1,2)+" nanometer KS "+name,'Delimiter','\t');
                        clear T_KS_nanometer_range
                    end
                    clear j k ks_5nm l name result result_5nm time
                end
                
                %Output together n-th nanometer
                
                %together_result_w(:,i)=[result_1nm(:,app.nm-378);zeros(table_length-height(table(result_1nm)),1)];    line 365
                %together_result_ks(:,i)=[ks_1nm(:,app.nm-378);zeros(p-height(table(ks_1nm)),1)];
                % TODO need to remove later because you cant save together
                % different timings
                clear result_1nm
                if islvm(app,file_path)
                    if app.nm>760; app.nm=760;end
                    together_result_ks=[ks_1nm(:,2+round(app.nm-380,-1)/10);zeros(p-height(table(ks_1nm)),1)];
                else
                    together_result_ks=[ks_1nm(:,app.nm-378);zeros(p-height(table(ks_1nm)),1)];
                end
                if app.Box_Analysis.Value==true
                    app.Count_Photo.Text ="Analysis";
                    
                    table_head=["One phase exponential fitting";"Best-fit values";"Span";"K";"Plateau";"Half Life";"Tau";"Y0";...
                        "95% confidence bounds";"Span";"";"K";"";"Plateau";"";"Half life";"";"Tau";"";"Y0";"";...
                        "Goodness of Fit";"Degrees of freedom";"R squared";"Sum of Squares";"Root mean squared";"Number of Y values"];
                    warning('off','MATLAB:xlswrite:AddSheet');
                    A_exposure=zeros([app.exp-5 app.cycle]);
                    A_decay=zeros([app.dec-5 app.cycle]);
                    
                    
                    [row,~]=find(together_result_ks(2:(app.dec+20))>1);
                    idx=row(1)+10;
                    for j=1:app.cycle
                        while 0.1<together_result_ks(idx)-together_result_ks(idx-1) || together_result_ks(idx)>1
                            idx=idx-1;
                        end
                        A_exposure(:,j)=together_result_ks(idx:idx+app.exp-6);
                        idx=idx+app.exp+5; if idx>length(together_result_ks)-1;break;end
                        while 0.1<together_result_ks(idx-1)-together_result_ks(idx) && together_result_ks(idx)<together_result_ks(idx-2)
                            idx=idx-1;
                        end
                        A_decay(:,j)=together_result_ks(idx:idx+app.dec-6);
                        idx=idx+app.dec+5;
                    end
                    
                    clear idx exposure_mean row
                    %Exposure
                    
                    [prism_column_exposure,prism_column_decay,~]=Curve_Fitting(app,A_exposure,A_decay,islvm(app,file_path));
                    prism_column_exposure(2,1)=strrep(string(name(i)),'.txt','');
                    prism_column_decay(2,1)=strrep(string(name(i)),'.txt','');
                    T=splitvars(table(table_head,prism_column_exposure,cell([27 2]),prism_column_decay));
                    writetable(T,app.path+app.slash+"prism.xlsx",'Sheet',i,'WriteVariableNames',false)
                    
                    clear T prism_column_decay prism_column_exposure
                    clear table_head X_analysis x together_result_ks table_head A_exposure A_decay
                end
                clear  k time_count cca_a cca_b cca_c_exp cca_c_dec
            end
            
            if (app.Box_Spec_KS.Value == true) && (app.Box_Save_Together.Value == true)
                %{
                T_concat_w=splitvars(table([(0:table_length-1)' round(together_result_w,app.rounding)]));
                T_concat_w.Properties.VariableNames = v;
                writetable(T_concat_w,app.path+app.slash+"Refl nanometer "+ app.nm,'Delimiter','\t');
                %}
                T_concat_ks=splitvars(table([(0:p-1)' round(together_result_ks,app.rounding)]));
                T_concat_ks.Properties.VariableNames = v;
                writetable(T_concat_ks,app.path+app.slash+"KS nanometer "+ app.nm,'Delimiter','\t');
                
            end
            clear i time_output_high time_output_low idx index nano nm nm_range opts nm nm_range T_concat_ks...
                T_concat_w table_length v together_result_w col row
            app.Count_Photo.Text ="Finished";
            drawnow
            Enable_All_Photo(app);
        end

        % Button pushed function: Select_Button_Light, 
        % Select_Files_Photo
        function Select_Pushed(app, event)
            [app.files,app.path]= uigetfile('MultiSelect','on','*.txt;*.tit;*.lvm');
            app.Select_Label_Light.Value = app.path;
            app.Select_Label_Photo.Value = app.path;
        end

        % Value changed function: Box_Spec_KS
        function Box_Spec_KS_Value_Changed(app, event)
            if app.Box_Spec_KS.Value == false
                app.Spec_Number.Enable = 'off';
                app.Box_Save_Together.Enable = 'off';
            else
                app.Spec_Number.Enable = 'on';
                app.Box_Save_Together.Enable = 'on';
            end
        end

        % Value changed function: Box_Spec_Range
        function Box_Spec_Range_Value_Changed(app, event)
            if app.Box_Spec_Range.Value == false
                app.Spec_Range_Top.Enable = 'off';
                app.Spec_Range_Bot.Enable = 'off';
                app.Just.Enable = 'off';
            else
                app.Spec_Range_Top.Enable = 'on';
                app.Spec_Range_Bot.Enable = 'on';
                app.Just.Enable = 'on';
            end
            
        end

        % Button pushed function: OnlyanalysisButton
        function Only_Analysis_Pushed(app, event)
            Disable_All_Photo(app);
            app.Select_Files_Photo.Enable='off';
            app.Select_Label_Photo.Enable='off';
            Initialize(app)
            [app.files,app.path]= uigetfile('MultiSelect','on','*.txt');
            app.Count_Photo.Text ="Analysing";
            drawnow
            X_imp=readtable(app.path+app.slash+app.files);
            v=X_imp.Properties.VariableNames;
            X=table2array(X_imp(2:end,2:end));
            
            table_head=["One phase exponential fitting";"Best-fit values";"Span";"K";"Plateau";"Half Life";"Tau";"Y0";...
                "95% confidence bounds";"Span";"";"K";"";"Plateau";"";"Half life";"";"Tau";"";"Y0";"";...
                "Goodness of Fit";"Degrees of freedom";"R squared";"Sum of Squares";"Root mean squared";"Number of Y values"];
            A_exposure=zeros([app.exp-5 app.cycle]);
            A_decay=zeros([app.dec-5 app.cycle]);
            warning('off','MATLAB:xlswrite:AddSheet');
            
            [~,N]=size(X);
            for k=1:N
                [row,~]=find(X(1:(app.dec+20),k)>1);
                idx=row(1)+10;
                
                for i=1:app.cycle
                    while 0.1<X(idx,k)-X(idx-1,k) || X(idx,k)>1
                        idx=idx-1;
                    end
                    A_exposure(:,i)=X(idx:idx+app.exp-6,k);
                    idx=idx+app.exp+5;
                    while 0.01<X(idx-1,k)-X(idx,k) || X(idx,k)<X(idx-2,k)
                        idx=idx-1;
                    end
                    A_decay(:,i)=X(idx:idx+app.dec-6,k);
                    idx=idx+app.dec+5;
                    
                end
                clear idx exposure_mean row
                
                %Exposure
                [prism_column_exposure,prism_column_decay,x0_values]=Curve_Fitting(app,A_exposure,A_decay);
                prism_column_exposure(2,1)=v(1,k+1);
                prism_column_decay(2,1)=v(1,k+1);
                prism_column_exposure(2,1)=strrep(string(app.files(k)),'.txt','');
                prism_column_decay(2,1)=strrep(string(app.files(k)),'.txt','');
                T=splitvars(table(table_head,prism_column_exposure,cell([27 2]),prism_column_decay));
                writetable(T,app.path+app.slash+"prism.xlsx",'Sheet',k,'WriteVariableNames',false)
                clear T prism_column_decay prism_column_exposure
            end
            clear files path N X A_decay A_exposure i k table_head v X_imp x cca_a cca_b cca_c_exp cca_c_dec
            
            app.Select_Files_Photo.Enable='on';
            app.Select_Label_Photo.Enable='on';
            app.Count_Photo.Text ="Finished";
            drawnow
            Enable_All_Photo(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LightningTab = uitab(app.TabGroup);
            app.LightningTab.Title = 'Lightning';
            app.L_Device = uilamp(app.LightningTab);
            app.L_Device.Visible = 'off';
            app.L_Files_Light = uilamp(app.LightningTab);
            app.L_Files_Light.Visible = 'off';
            app.Execute_Light = uibutton(app.LightningTab, 'push');
            app.Execute_Light.ButtonPushedFcn = createCallbackFcn(app, @Execute_Light_Pushed, true);
            app.Execute_Light.Text = 'Execute';
            app.Count_Light = uilabel(app.LightningTab);
            app.Count_Light.Text = '';
            app.Ex_Label_Light = uilabel(app.LightningTab);
            app.Ex_Label_Light.Text = 'Execute processing of the files';
            app.SelectmeasurementdeviceLabel = uilabel(app.LightningTab);
            app.SelectmeasurementdeviceLabel.Text = 'Select measurement device';
            app.Select_Device = uidropdown(app.LightningTab);
            app.Select_Device.Items = {'Select', 'STS', 'USB 2000', 'Avantes 8 Dual', 'Avantes 6'};
            app.Select_Device.Value = 'Select';
            app.Select_Button_Light = uibutton(app.LightningTab, 'push');
            app.Select_Button_Light.ButtonPushedFcn = createCallbackFcn(app, @Select_Pushed, true);
            app.Select_Button_Light.Text = 'Select';
            app.SelectingfilesforprocessingEditFieldLabel = uilabel(app.LightningTab);
            app.SelectingfilesforprocessingEditFieldLabel.Text = 'Selecting files for processing';
            app.Select_Label_Light = uieditfield(app.LightningTab, 'text');
            app.Select_Label_Light.Editable = 'off';
            app.Select_Label_Light.Value = ' ';
            app.Lam = uilamp(app.LightningTab);
            app.PhotochromTab = uitab(app.TabGroup);
            app.PhotochromTab.Title = 'Photochrom';
            app.Execute_Photo = uibutton(app.PhotochromTab, 'push');
            app.Execute_Photo.ButtonPushedFcn = createCallbackFcn(app, @Execute_Photo_Pushed, true);
            app.Execute_Photo.Text = 'Execute';
            app.Count_Photo = uilabel(app.PhotochromTab);
            app.Count_Photo.Text = '';
            app.Ex_Label_Photo = uilabel(app.PhotochromTab);
            app.Ex_Label_Photo.Text = 'Execute processing of the files';
            app.Select_Files_Photo = uibutton(app.PhotochromTab, 'push');
            app.Select_Files_Photo.ButtonPushedFcn = createCallbackFcn(app, @Select_Pushed, true);
            app.Select_Files_Photo.Text = 'Select';
            app.SelectingfilesforprocessingEditFieldLabel_2 = uilabel(app.PhotochromTab);
            app.SelectingfilesforprocessingEditFieldLabel_2.Text = 'Selecting files for processing';
            app.Select_Label_Photo = uieditfield(app.PhotochromTab, 'text');
            app.Select_Label_Photo.Editable = 'off';
            app.Select_Label_Photo.Value = ' ';
            app.ReflectanceLabel = uilabel(app.PhotochromTab);
            app.ReflectanceLabel.Text = 'Reflectance';
            app.Box_1nm = uicheckbox(app.PhotochromTab);
            app.Box_1nm.Text = '1 nm timetable';
            app.Box_5nm = uicheckbox(app.PhotochromTab);
            app.Box_5nm.Text = '5 nm timetable';
            app.Save_Label = uilabel(app.PhotochromTab);
            app.Save_Label.Text = 'What to save';
            app.Spec_Number = uieditfield(app.PhotochromTab, 'numeric');
            app.Spec_Number.Limits = [380 780];
            app.Spec_Number.RoundFractionalValues = 'on';
            app.Spec_Number.ValueDisplayFormat = '%.0f';
            app.Spec_Number.Value = 570;
            app.KSLabel = uilabel(app.PhotochromTab);
            app.KSLabel.Text = 'K/S';
            app.Box_Spec_KS = uicheckbox(app.PhotochromTab);
            app.Box_Spec_KS.ValueChangedFcn = createCallbackFcn(app, @Box_Spec_KS_Value_Changed, true);
            app.Box_Spec_KS.Text = 'Values fo specified wavelength';
            app.Box_Spec_Range = uicheckbox(app.PhotochromTab);
            app.Box_Spec_Range.ValueChangedFcn = createCallbackFcn(app, @Box_Spec_Range_Value_Changed, true);
            app.Box_Spec_Range.Text = 'Values for specified range of wavelengths';
            app.Box_KS_1nm = uicheckbox(app.PhotochromTab);
            app.Box_KS_1nm.Text = '1 nm timetable';
            app.Box_KS_5nm = uicheckbox(app.PhotochromTab);
            app.Box_KS_5nm.Text = '5 nm timetable';
            app.Spec_Range_Top = uieditfield(app.PhotochromTab, 'numeric');
            app.Spec_Range_Top.Limits = [380 780];
            app.Spec_Range_Top.RoundFractionalValues = 'on';
            app.Spec_Range_Top.ValueDisplayFormat = '%.0f';
            app.Spec_Range_Top.Enable = 'off';
            app.Spec_Range_Top.Value = 680;
            app.Just = uilabel(app.PhotochromTab);
            app.Just.Enable = 'off';
            app.Just.Text = ':';
            app.Spec_Range_Bot = uieditfield(app.PhotochromTab, 'numeric');
            app.Spec_Range_Bot.Limits = [380 780];
            app.Spec_Range_Bot.RoundFractionalValues = 'on';
            app.Spec_Range_Bot.ValueDisplayFormat = '%.0f';
            app.Spec_Range_Bot.Enable = 'off';
            app.Spec_Range_Bot.Value = 420;
            app.Box_Save_Together = uicheckbox(app.PhotochromTab);
            app.Box_Save_Together.Text = 'Save together';
            app.Box_Save_Together.Value = true;
            app.Time_Range_Bot = uieditfield(app.PhotochromTab, 'numeric');
            app.Time_Range_Bot.LowerLimitInclusive = 'off';
            app.Time_Range_Bot.UpperLimitInclusive = 'off';
            app.Time_Range_Bot.Limits = [1 Inf];
            app.Time_Range_Bot.RoundFractionalValues = 'on';
            app.Time_Range_Bot.ValueDisplayFormat = '%.0f';
            app.Time_Range_Bot.Value = 900;
            app.Just_2 = uilabel(app.PhotochromTab);
            app.Just_2.Text = ':';
            app.Time_Range_Top = uieditfield(app.PhotochromTab, 'numeric');
            app.Time_Range_Top.LowerLimitInclusive = 'off';
            app.Time_Range_Top.UpperLimitInclusive = 'off';
            app.Time_Range_Top.Limits = [1 Inf];
            app.Time_Range_Top.RoundFractionalValues = 'on';
            app.Time_Range_Top.ValueDisplayFormat = '%.0f';
            app.Time_Range_Top.Value = 150;
            app.Cycle_Count = uieditfield(app.PhotochromTab, 'numeric');
            app.Cycle_Count.Limits = [1 Inf];
            app.Cycle_Count.Value = 5;
            app.CyclecountLabel = uilabel(app.PhotochromTab);
            app.CyclecountLabel.Text = 'Cycle count';
            app.UVonoffLabel = uilabel(app.PhotochromTab);
            app.UVonoffLabel.Text = 'UV on/off';
            app.DeviceDropDownLabel = uilabel(app.PhotochromTab);
            app.DeviceDropDownLabel.Text = 'Device';
            app.Device_Photo = uidropdown(app.PhotochromTab);
            app.Device_Photo.Items = {'Photochrome 2', 'Photochrome 3'};
            app.Device_Photo.Value = 'Photochrome 2';
            app.Force_Stop = uibutton(app.PhotochromTab, 'state');
            app.Force_Stop.Visible = 'off';
            app.Force_Stop.Text = 'Force Stop';
            app.Force_Stop.Value = true;
            app.Time_Count_Photo = uilabel(app.PhotochromTab);
            app.Time_Count_Photo.Text = '';
            app.Box_Analysis = uicheckbox(app.PhotochromTab);
            app.Box_Analysis.Text = 'Analysis';
            app.Box_Analysis.Value = true;
            app.OnlyanalysisButton = uibutton(app.PhotochromTab, 'push');
            app.OnlyanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @Only_Analysis_Pushed, true);
            app.OnlyanalysisButton.Text = 'Only analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = light_app_v_9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end