classdef idunno < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectmodeLabel                matlab.ui.control.Label
        SelectModeDropDown             matlab.ui.control.DropDown
        PigmentDropDownLabel           matlab.ui.control.Label
        PigmentDropDown                matlab.ui.control.DropDown
        JednotkyDropDownLabel          matlab.ui.control.Label
        JednotkyDropDown               matlab.ui.control.DropDown
        LightSourceDropDownLabel       matlab.ui.control.Label
        LightSourceDropDown            matlab.ui.control.DropDown
        UnitsDropDownLabel             matlab.ui.control.Label
        UnitsDropDown                  matlab.ui.control.DropDown
        AmountEditFieldLabel           matlab.ui.control.Label
        AmountEditField                matlab.ui.control.NumericEditField
        BlackbodytemperatureEditFieldLabel  matlab.ui.control.Label
        BlackbodytemperatureEditField  matlab.ui.control.NumericEditField
        NarrowbandpeakEditFieldLabel   matlab.ui.control.Label
        NarrowbandpeakEditField        matlab.ui.control.NumericEditField
        NarrowbandFWHMEditFieldLabel   matlab.ui.control.Label
        NarrowbandFWHMEditField        matlab.ui.control.NumericEditField
        PeakspectralirradianceEditFieldLabel  matlab.ui.control.Label
        PeakspectralirradianceEditField  matlab.ui.control.NumericEditField
        PhotopicilluminanceEditFieldLabel  matlab.ui.control.Label
        PhotopicilluminanceEditField   matlab.ui.control.NumericEditField
        CyanopicEditFieldLabel         matlab.ui.control.Label
        CyanopicEditField              matlab.ui.control.NumericEditField
        MelanopicEditFieldLabel        matlab.ui.control.Label
        MelanopicEditField             matlab.ui.control.NumericEditField
        RhodopicEditFieldLabel         matlab.ui.control.Label
        RhodopicEditField              matlab.ui.control.NumericEditField
        ChloropicEditFieldLabel        matlab.ui.control.Label
        ChloropicEditField             matlab.ui.control.NumericEditField
        ErythropicEditFieldLabel       matlab.ui.control.Label
        ErythropicEditField            matlab.ui.control.NumericEditField
        UIAxes1                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        nmLabel                        matlab.ui.control.Label
        nmLabel_2                      matlab.ui.control.Label
        KLabel                         matlab.ui.control.Label
        AmountUnitsLabel               matlab.ui.control.Label
        IrradianceEditFieldLabel       matlab.ui.control.Label
        IrradianceEditField            matlab.ui.control.NumericEditField
        PhotonfluxEditFieldLabel       matlab.ui.control.Label
        PhotonfluxEditField            matlab.ui.control.NumericEditField
        LogphotonfluxEditFieldLabel    matlab.ui.control.Label
        LogphotonfluxEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        test % Description
        src % Description
    end
    
    methods (Access = private)
        
        function changeUI(app, source, value)
            switch source
                case 'selectMode'
                    if value == app.SelectModeDropDown.Items(3)
                        app.LightSourceDropDown.Enable = 1;
                        app.UnitsDropDown.Enable = 1;
                        app.AmountEditField.Enable = 1;
                    else
                        app.LightSourceDropDown.Enable = 0;
                        app.UnitsDropDown.Enable = 0;
                        app.AmountEditField.Enable = 0;
                    end
                    
                case 'lightSource'
                    
                    
                case 'lightUnits'
                    if value == "L"
                        app.AmountUnitsLabel.Text = "lux";
                    elseif value == "P"
                        app.AmountUnitsLabel.Text = "\microW/cm^2";
                    else
                        app.AmountUnitsLabel.Text = "log_{10}(1/cm^2/s)";
                    end
                    
                case 'amount'
                    
                    
                case 'bTemp'
                    
                    
                case 'narrowBPeak'
                    
                    
                case 'narrowBFWHM'
                    
                    
            end
        end
        
        function main(app)
            if string(app.SelectModeDropDown.Value) == app.SelectModeDropDown.Items{1}
                importedValues = app.test;
                importedValues5nm = importedValues(1:end-4)+importedValues(2:end-3)+importedValues(3:end-2)+importedValues(4:end-1)+importedValues(5:end);
                importedValues5nm = importedValues5nm(1:5:405);
            else
                importedValues5nm = (380:5:780)./100;
            end
            % Value 1.19104295262457e+29 represents 2*hc^2 where h is Planck and c is the speed of light 
            % and value 14387773.5382772 represents hc/k where k is Boltzmann constant.
            % These are used fo blackbody radiation function (2hc^2/lambda^5)*1/(exp(hc/kTlambda)-1)
            range = 380:5:780;
            intensity=(1.19104295262457e+29./(range.^5)).*(1./(exp(14387773.5382772./(app.BlackbodytemperatureEditField.Value.*range))-1));
            % N = e^(-x^2*ln(2)), where x = lambda-lambda_max / 0.5*FWHMpeak
            % This can be rewritten as used below  N = e^(-x^2*4*ln(2)), where x = lambda-lambda_max / FWHMpeak
            % Or N=16^(-x^2), for complete simplicity, where x = lambda-lambda_max / FWHMpeak
            N=exp(-( (range-app.NarrowbandpeakEditField.Value)/(app.NarrowbandFWHMEditField.Value) ).^2*log(16));
            app.src.sourceData.relPowDis(:,6) = intensity./sum(intensity);
            app.src.sourceData.relPowDis(:,5) = N/sum(N);
            app.src.sourceData.relPowDis(:,5) = app.src.sourceData.relPowDis(:,5) .* ((N/sum(N))>10e-5)';
            app.src.sourceData.relPowDis(:,8) = importedValues5nm./sum(importedValues5nm);


            if string(app.SelectModeDropDown.Value) == app.SelectModeDropDown.Items{3}
                i = find(contains(app.LightSourceDropDown.Items, app.LightSourceDropDown.Value));
                if app.LightSourceDropDown.Value ~= 'E'
                    app.PeakspectralirradianceEditField.Value = 375+5*find(max(app.src.sourceData.relPowDis(:,i))==app.src.sourceData.relPowDis(:,i));
                else
                    app.PeakspectralirradianceEditField.Value = Inf;
                end
                L = sum(app.src.sourceData.relPowDis(:,i) .* app.src.sourceData.refFun.photopicSI);
                P = sum(app.src.sourceData.relPowDis(:,i));
                Q = sum(app.src.sourceData.relPowDis(:,i) .* range');
                % Value 6.83001547790227 represents Photometry constant Km/100
                % Value 5034116651.11454 represents 10^-15/hc, where h is Planck constant a c is the speed of light
                LP = 6.83001547790227*L/P;
                QP = 5034116651.11454*Q/P;
                
                units = getUnits(app, importedValues5nm);
                
                if app.UnitsDropDown.Value == 'Q'
                    app.PhotopicilluminanceEditField.Value = LP*units/QP;
                    app.IrradianceEditField.Value = units/QP;
                    app.PhotonfluxEditField.Value = units;
                    app.LogphotonfluxEditField.Value = log10(units);
                elseif app.UnitsDropDown.Value == 'P'
                    app.PhotopicilluminanceEditField.Value = LP*units;
                    app.IrradianceEditField.Value = units;
                    app.PhotonfluxEditField.Value = QP*units;
                    app.LogphotonfluxEditField.Value = log10(QP*units);
                else
                    app.PhotopicilluminanceEditField.Value = units;
                    app.IrradianceEditField.Value = units/LP;
                    app.PhotonfluxEditField.Value = QP*units/LP;
                    app.LogphotonfluxEditField.Value = log10(QP*units/LP);
                end
                
                [~] = getLambda(app, app.src.sourceData.relPowDis(:,i), true);
                plotGraphs(app, app.src.sourceData.relPowDis(:,i))
            
            else
                L = sum(app.src.sourceData.relPowDis(:,8) .* app.src.sourceData.refFun.photopicSI);
                P = sum(app.src.sourceData.relPowDis(:,8));
                Q = sum(app.src.sourceData.relPowDis(:,8) .* range');
                LP = 6.83001547790227*L/P;
                QP = 5034116651.11454*Q/P;
                units = getUnits(app, importedValues5nm);
                app.PhotopicilluminanceEditField.Value = LP*units;
                app.IrradianceEditField.Value = units;
                app.PhotonfluxEditField.Value = QP*units;
                app.LogphotonfluxEditField.Value = log10(QP*units);
                [~] = getLambda(app, app.src.sourceData.relPowDis(:,8), true);
                plotGraphs(app, app.src.sourceData.relPowDis(:,8))
            end
        end
        
        function units = getUnits(app, data)
            if string(app.SelectModeDropDown.Value) ~= app.SelectModeDropDown.Items{3}
                units = sum(data);
            elseif app.UnitsDropDown.Value == 'Q'
                units = 10^(app.AmountEditField.Value);
            else
                units = app.AmountEditField.Value;
            end
        end
        
        function lambda = getLambda(app, activeSource, reference)
            if reference==true
                divide = string(app.JednotkyDropDown.Value) == app.JednotkyDropDown.Items(1);
                app.CyanopicEditField.Value = getLambda(app, activeSource, app.src.sourceData.refFun.cyanopic)/(1+12.2516160379921*divide);
                app.MelanopicEditField.Value = getLambda(app, activeSource, app.src.sourceData.refFun.melanopic)/(1+7.32410568771484*divide);
                app.RhodopicEditField.Value = getLambda(app, activeSource, app.src.sourceData.refFun.rhodopic)/(1+6.05360483304084*divide);
                app.ChloropicEditField.Value = getLambda(app, activeSource, app.src.sourceData.refFun.chloropic)/(1+5.20038450812400*divide);
                app.ErythropicEditField.Value = getLambda(app, activeSource, app.src.sourceData.refFun.erythropic)/(1+4.78845594617544*divide);
                lambda = [];
            else
                lambda = 6.83001547790227*app.IrradianceEditField.Value*sum(activeSource.*reference)*sum(app.src.sourceData.refFun.photopicSI)/sum(reference);
            end
        end
        
        function changeAmountUnits(app, value)
            switch value
                case 'L'
                    app.AmountEditField.ValueDisplayFormat = '%11.4g lux';
                    app.AmountUnitsLabel.Text = 'lux';
                case 'P'
                    % '%11.4g µW/cm²'
                    app.AmountEditField.ValueDisplayFormat = char([37 49 49 46 52 103 32 181 87 47 99 109 178]);
                    % '%11.4g log₁₀(1/cm²/s)'
                    app.AmountUnitsLabel.Text = char([37 49 49 46 52 103 32 108 111 103 8321 8320 40 49 47 99 109 178 47 115 41]);
                case 'Q'
                    % '%11.4g log₁₀(1/cm²/s)'
                    app.AmountEditField.ValueDisplayFormat = char([37 49 49 46 52 103 32 108 111 103 8321 8320 40 49 47 99 109 178 47 115 41]);
                    % '%11.4g log₁₀(1/cm²/s)'
                    app.AmountUnitsLabel.Text = char([37 49 49 46 52 103 32 108 111 103 8321 8320 40 49 47 99 109 178 47 115 41]);
            end
            
        end
        
        function plotGraphs(app, activeSource)
            %Plot number 1
            i = find(contains(app.PigmentDropDown.Items, app.PigmentDropDown.Value))+1;
            plot(app.UIAxes1, 380:5:780, activeSource.*app.IrradianceEditField.Value, 380:5:780, ...
                activeSource.*app.IrradianceEditField.Value.*app.src.sourceData.refFun.values{2,i}./max(app.src.sourceData.refFun.values{2,i}))
            
            
            %plot number 2
            bar(app.UIAxes2, [app.CyanopicEditField.Value, app.MelanopicEditField.Value, app.RhodopicEditField.Value,...
                app.ChloropicEditField.Value, app.ErythropicEditField.Value])
            
            %plot number 3
            if string(app.JednotkyDropDown.Value) == app.JednotkyDropDown.Items(2)
                plot(app.UIAxes3, 380:5:780, app.src.sourceData.refFun.allRaw)
            else
                plot(app.UIAxes3, 380:5:780, app.src.sourceData.refFun.allRaw./app.src.sourceData.refFun.allConstants(3,:))
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if 1==1
            app.test = [0;0;0.000189810000000000;0.000158711666667000;9.90916666670000e-05;0.000147473333333000;0.000154343333333000;0.000114352833333000;0.000100775000000000;0.000103009500000000];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
            app.src = load('sourceData.mat');
            end
            main(app)
        end

        % Value changed function: AmountEditField, 
        % BlackbodytemperatureEditField, JednotkyDropDown, 
        % LightSourceDropDown, NarrowbandFWHMEditField, 
        % NarrowbandpeakEditField, PigmentDropDown, 
        % SelectModeDropDown, UnitsDropDown
        function SelectionChanged(app, event)
            changeUI(app, event.Source.Tag, string(event.Value))
            main(app)
            if string(event.Source.Tag) == "lightUnits"
                changeAmountUnits(app, event.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectmodeLabel = uilabel(app.UIFigure);
            app.SelectmodeLabel.Text = 'Select mode';
            app.SelectModeDropDown = uidropdown(app.UIFigure);
            app.SelectModeDropDown.Items = {'1nm spectral data', '5nm spectral data', 'Approximate mode'};
            app.SelectModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionChanged, true);
            app.SelectModeDropDown.Tag = 'selectMode';
            app.SelectModeDropDown.Tooltip = {'Choose mode of operation'; 'If spectral data measured outside the eye is not used, the differences between this and the actual data used may lead to errors. In particular, the spectra of most standard illuminants options are unlikely to be reproduced exactly in practice.'};
            app.SelectModeDropDown.Value = '1nm spectral data';
            app.PigmentDropDownLabel = uilabel(app.UIFigure);
            app.PigmentDropDownLabel.Text = 'Pigment';
            app.PigmentDropDown = uidropdown(app.UIFigure);
            app.PigmentDropDown.Items = {'Erythropic', 'Chloropic', 'Rhodopic', 'Melanopic', 'Cyanopic'};
            app.PigmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionChanged, true);
            app.PigmentDropDown.Tooltip = {'Select pigment'; 'Choose between cyanopic, melanopic, rhodopic, chloropic and erythropic weighting functions for calculating the red line  in the Spectral Power Distribution chart.'};
            app.PigmentDropDown.Value = 'Erythropic';
            app.JednotkyDropDownLabel = uilabel(app.UIFigure);
            app.JednotkyDropDownLabel.Text = 'Jednotky';
            app.JednotkyDropDown = uidropdown(app.UIFigure);
            app.JednotkyDropDown.Items = {'SI  and CIE units', 'IWCNP units'};
            app.JednotkyDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionChanged, true);
            app.JednotkyDropDown.Tooltip = {'Choose preferred unit convention'; 'The SI and CIE recommend irradiance units to present spectrally weighted quantities except photopic quantities. Alternatively, the IWCNP proposed equivalent illuminance quantities, normalised to agree to photopic quantities for an equal energy spectrum.'; ''};
            app.JednotkyDropDown.Value = 'SI  and CIE units';
            app.LightSourceDropDownLabel = uilabel(app.UIFigure);
            app.LightSourceDropDownLabel.Text = 'Light Source';
            app.LightSourceDropDown = uidropdown(app.UIFigure);
            app.LightSourceDropDown.Items = {'A', 'D', 'F', 'L', 'N', 'B', 'E'};
            app.LightSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionChanged, true);
            app.LightSourceDropDown.Tag = 'lightSource';
            app.LightSourceDropDown.Enable = 'off';
            app.LightSourceDropDown.Tooltip = {'Light source'; 'A = Illuminant A (incandescent, 2856K)'; 'D = Illuminant D65 (daylight, 6504K)'; 'F = Illuminant F (fluorescent, CCT ~4000K)'; 'L = White LED (blue+phospor, CCT ~4730K)'; 'N = Narrowband, incl monochromatic'; 'B = Blackbody spectra'; 'E = equal energy (for normalisation)'; ''};
            app.LightSourceDropDown.Value = 'A';
            app.UnitsDropDownLabel = uilabel(app.UIFigure);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UIFigure);
            app.UnitsDropDown.Items = {'L - illuminance', 'P - irradiance', 'Q - log(photon flux)'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionChanged, true);
            app.UnitsDropDown.Tag = 'lightUnits';
            app.UnitsDropDown.Enable = 'off';
            app.UnitsDropDown.Tooltip = {'Units of measured light quantity'; 'L = illuminance ("lux")'; 'P = irradiance ("power")'; 'Q = log (photon flux) ("quanta")'};
            app.UnitsDropDown.Value = 'L - illuminance';
            app.AmountEditFieldLabel = uilabel(app.UIFigure);
            app.AmountEditFieldLabel.Text = 'Amount';
            app.AmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountEditField.ValueDisplayFormat = '%11.4g lux';
            app.AmountEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionChanged, true);
            app.AmountEditField.Tag = 'amount';
            app.AmountEditField.Enable = 'off';
            app.AmountEditField.Value = 10;
            app.BlackbodytemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.BlackbodytemperatureEditFieldLabel.Text = 'Blackbody temperature';
            app.BlackbodytemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlackbodytemperatureEditField.ValueDisplayFormat = '%11.4g K';
            app.BlackbodytemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionChanged, true);
            app.BlackbodytemperatureEditField.Tag = 'bTemp';
            app.BlackbodytemperatureEditField.Tooltip = {'Blackbody temperature'; 'Values from 2000 K to 20,000 K'};
            app.BlackbodytemperatureEditField.Value = 6500;
            app.NarrowbandpeakEditFieldLabel = uilabel(app.UIFigure);
            app.NarrowbandpeakEditFieldLabel.Text = 'Narrowband peak';
            app.NarrowbandpeakEditField = uieditfield(app.UIFigure, 'numeric');
            app.NarrowbandpeakEditField.ValueDisplayFormat = '%11.4g nm';
            app.NarrowbandpeakEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionChanged, true);
            app.NarrowbandpeakEditField.Tag = 'narrowBPeak';
            app.NarrowbandpeakEditField.Tooltip = {'Narrowband peak'; 'Values from 400 nm to 750 nm'};
            app.NarrowbandpeakEditField.Value = 420;
            app.NarrowbandFWHMEditFieldLabel = uilabel(app.UIFigure);
            app.NarrowbandFWHMEditFieldLabel.Text = 'Narrowband FWHM';
            app.NarrowbandFWHMEditField = uieditfield(app.UIFigure, 'numeric');
            app.NarrowbandFWHMEditField.ValueDisplayFormat = '%11.4g nm';
            app.NarrowbandFWHMEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionChanged, true);
            app.NarrowbandFWHMEditField.Tag = 'narrowBFWHM';
            app.NarrowbandFWHMEditField.Tooltip = {'Full width half maximum, FWHM'; 'Values from 5 nm to 50 nm'};
            app.NarrowbandFWHMEditField.Value = 42;
            app.PeakspectralirradianceEditFieldLabel = uilabel(app.UIFigure);
            app.PeakspectralirradianceEditFieldLabel.Text = 'Peak spectral irradiance';
            app.PeakspectralirradianceEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeakspectralirradianceEditField.ValueDisplayFormat = '%11.4g nm';
            app.PeakspectralirradianceEditField.Editable = 'off';
            app.PhotopicilluminanceEditFieldLabel = uilabel(app.UIFigure);
            app.PhotopicilluminanceEditFieldLabel.Text = 'Photopic illuminance';
            app.PhotopicilluminanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhotopicilluminanceEditField.ValueDisplayFormat = '%11.4g lux';
            app.CyanopicEditFieldLabel = uilabel(app.UIFigure);
            app.CyanopicEditFieldLabel.Text = 'Cyanopic';
            app.CyanopicEditField = uieditfield(app.UIFigure, 'numeric');
            app.MelanopicEditFieldLabel = uilabel(app.UIFigure);
            app.MelanopicEditFieldLabel.Text = 'Melanopic';
            app.MelanopicEditField = uieditfield(app.UIFigure, 'numeric');
            app.RhodopicEditFieldLabel = uilabel(app.UIFigure);
            app.RhodopicEditFieldLabel.Text = 'Rhodopic';
            app.RhodopicEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChloropicEditFieldLabel = uilabel(app.UIFigure);
            app.ChloropicEditFieldLabel.Text = 'Chloropic';
            app.ChloropicEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErythropicEditFieldLabel = uilabel(app.UIFigure);
            app.ErythropicEditFieldLabel.Text = 'Erythropic';
            app.ErythropicEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, 'vlnová délka (nm)')
            ylabel(app.UIAxes1, 'spektrálni ozářenost (μW.cm⁻².nm⁻¹)')
            app.UIAxes1.XLim = [380 780];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, 'vážená ozářenost (μW.cm⁻²)')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = {'sc'; 'z'; 'r'; 'mc'; 'lc'};
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, 'vlnová délka (nm)')
            ylabel(app.UIAxes3, 'poměrná absorbance')
            app.nmLabel = uilabel(app.UIFigure);
            app.nmLabel.Text = 'nm';
            app.nmLabel_2 = uilabel(app.UIFigure);
            app.nmLabel_2.Text = 'nm';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'K';
            app.AmountUnitsLabel = uilabel(app.UIFigure);
            app.AmountUnitsLabel.Text = 'lux';
            app.IrradianceEditFieldLabel = uilabel(app.UIFigure);
            app.IrradianceEditFieldLabel.Text = 'Irradiance';
            app.IrradianceEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhotonfluxEditFieldLabel = uilabel(app.UIFigure);
            app.PhotonfluxEditFieldLabel.Text = 'Photon flux';
            app.PhotonfluxEditField = uieditfield(app.UIFigure, 'numeric');
            app.LogphotonfluxEditFieldLabel = uilabel(app.UIFigure);
            app.LogphotonfluxEditFieldLabel.Text = 'Log photon flux';
            app.LogphotonfluxEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = idunno

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end